classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NAperfeatureEditField       matlab.ui.control.NumericEditField
        NAperfeatureEditFieldLabel  matlab.ui.control.Label
        NApercaseEditField          matlab.ui.control.NumericEditField
        NApercaseEditFieldLabel     matlab.ui.control.Label
        BatchPercEditField          matlab.ui.control.NumericEditField
        BatchEditFieldLabel         matlab.ui.control.Label
        NbatchesEditField           matlab.ui.control.NumericEditField
        NbatchesEditFieldLabel      matlab.ui.control.Label
        minAUCEditField             matlab.ui.control.NumericEditField
        minAUCLabel                 matlab.ui.control.Label
        maxAUCEditField             matlab.ui.control.NumericEditField
        maxAUCEditFieldLabel        matlab.ui.control.Label
        MLmodelEditField            matlab.ui.control.EditField
        MLmodelEditFieldLabel       matlab.ui.control.Label
        NcasesEditField             matlab.ui.control.EditField
        NcasesEditFieldLabel        matlab.ui.control.Label
        SimulateButton              matlab.ui.control.Button
        predictivemarkersEditField  matlab.ui.control.NumericEditField
        predictivemarkersEditFieldLabel  matlab.ui.control.Label
        RelativedistributionlabelsEditField  matlab.ui.control.NumericEditField
        RelativedistributionlabelsEditFieldLabel  matlab.ui.control.Label
        NfeaturesEditField          matlab.ui.control.NumericEditField
        NfeaturesEditFieldLabel     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


%     methods (Access = public)
%         function results = simulFunc(app)
%             IN.Nfeats = app.NfeaturesEditField.Value;
%             IN.Nmarkers = app.predictivemarkersEditField.Value;
%             IN.Ncases = str2num(app.NcasesEditField.Value);
%             IN.eventprob = app.RelativedistributionlabelsEditField.Value;
% 
%             results = nk_SimulateML(IN, app.UIAxes);
%         end
%     end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NfeaturesEditField
        function NfeaturesEditFieldValueChanged(app, event)
          
        end

        % Value changed function: predictivemarkersEditField
        function predictivemarkersEditFieldValueChanged(app, event)
      
        end

        % Value changed function: RelativedistributionlabelsEditField
        function RelativedistributionlabelsEditFieldValueChanged(app, event)
          
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            IN.Nfeats = app.NfeaturesEditField.Value;
            IN.Nmarkers = app.predictivemarkersEditField.Value;
            IN.Ncases = str2num(app.NcasesEditField.Value);
            IN.eventprob = app.RelativedistributionlabelsEditField.Value;
            IN.AUCmax = app.maxAUCEditField.Value;
            IN.AUCmin = app.minAUCEditField.Value;
            IN.Nbatches = app.NbatchesEditField.Value;
            IN.BatchPerc = app.BatchPercEditField.Value;
            IN.NcasesMiss = app.RelativedistributionlabelsEditField.Value;
            IN.NfeatsMiss = app.RelativedistributionlabelsEditField.Value;
            IN.algorithm = str2num(app.MLmodelEditField.Value);
            simSamp = nk_SimulateML(IN, app.UIAxes);
            %app.ResultsTextArea = evalc(simSamp);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
%             newfigure = figure;
%             copyobj(app.UIAxes, newfigure);
%             mypath = app.PathtosaveresultsEditField;  
%             newFile = fullfile(mypath, 'sampleSizeSimulation.fig');
%             hgsave(newfigure, newFile);
%             export_fig(newfigure, 'sampleSizeSimulation.pdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sample size simulation')
            xlabel(app.UIAxes, 'N Cases')
            ylabel(app.UIAxes, 'Balanced Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.NfeaturesEditFieldLabel = uilabel(app.UIFigure);
            app.NfeaturesEditFieldLabel.Text = 'N features';
            app.NfeaturesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NfeaturesEditField.ValueChangedFcn = createCallbackFcn(app, @NfeaturesEditFieldValueChanged, true);
            app.NfeaturesEditField.Value = 100;
            app.RelativedistributionlabelsEditFieldLabel = uilabel(app.UIFigure);
            app.RelativedistributionlabelsEditFieldLabel.Text = 'Relative distribution labels';
            app.RelativedistributionlabelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.RelativedistributionlabelsEditField.ValueChangedFcn = createCallbackFcn(app, @RelativedistributionlabelsEditFieldValueChanged, true);
            app.RelativedistributionlabelsEditField.Value = 0.5;
            app.predictivemarkersEditFieldLabel = uilabel(app.UIFigure);
            app.predictivemarkersEditFieldLabel.Text = '% predictive markers';
            app.predictivemarkersEditField = uieditfield(app.UIFigure, 'numeric');
            app.predictivemarkersEditField.ValueChangedFcn = createCallbackFcn(app, @predictivemarkersEditFieldValueChanged, true);
            app.predictivemarkersEditField.Value = 0.2;
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.NcasesEditFieldLabel = uilabel(app.UIFigure);
            app.NcasesEditFieldLabel.Text = 'N cases';
            app.NcasesEditField = uieditfield(app.UIFigure, 'text');
            app.NcasesEditField.Value = '[100 200 300 400]';
            app.MLmodelEditFieldLabel = uilabel(app.UIFigure);
            app.MLmodelEditFieldLabel.Text = 'ML model';
            app.MLmodelEditField = uieditfield(app.UIFigure, 'text');
            app.MLmodelEditField.Value = 'LIBKERNSVM';
            app.maxAUCEditFieldLabel = uilabel(app.UIFigure);
            app.maxAUCEditFieldLabel.Text = 'max AUC';
            app.maxAUCEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxAUCEditField.Value = 0.7;
            app.minAUCLabel = uilabel(app.UIFigure);
            app.minAUCLabel.Text = 'min AUC';
            app.minAUCEditField = uieditfield(app.UIFigure, 'numeric');
            app.minAUCEditField.Value = 0.5;
            app.NbatchesEditFieldLabel = uilabel(app.UIFigure);
            app.NbatchesEditFieldLabel.Text = 'N batches';
            app.NbatchesEditField = uieditfield(app.UIFigure, 'numeric');
            app.BatchEditFieldLabel = uilabel(app.UIFigure);
            app.BatchEditFieldLabel.Text = 'Batch %';
            app.BatchPercEditField = uieditfield(app.UIFigure, 'numeric');
            app.BatchPercEditField.Value = 0.1;
            app.NApercaseEditFieldLabel = uilabel(app.UIFigure);
            app.NApercaseEditFieldLabel.Text = '% NA per case';
            app.NApercaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.NAperfeatureEditFieldLabel = uilabel(app.UIFigure);
            app.NAperfeatureEditFieldLabel.Text = '% NA per feature';
            app.NAperfeatureEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end