classdef appMLI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        AnalysisDropDown               matlab.ui.control.DropDown
        AnalysisDropDownLabel          matlab.ui.control.Label
        LabelDropDown                  matlab.ui.control.DropDown
        LabelDropDownLabel             matlab.ui.control.Label
        BinaryclassifierDropDown       matlab.ui.control.DropDown
        BinaryclassifierDropDownLabel  matlab.ui.control.Label
        AnalysisNoLabel                matlab.ui.control.Label
        ModalityDropDown               matlab.ui.control.DropDown
        ModalityDropDownLabel          matlab.ui.control.Label
        SortfeaturesCheckBox           matlab.ui.control.CheckBox
        PlotButton                     matlab.ui.control.Button
        SelectsubjectDropDown          matlab.ui.control.DropDown
        SelectsubjectDropDownLabel     matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TitleLabel                     matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        cases 
        %curCase
        MLI
        featnames
        labels
        groupnames
        Ys
        curHandles
        curModalityType
        curModality
    end
    
    properties (Access = public)
        stMLI
        CLP
        CLR1
        CLR2
    end

    methods (Access = public)
        
        function updateFcn(app,newHandles)
            if ~isequal(app.curHandles, newHandles)
                app.startupFcn(newHandles)
            end
        end
    end
    
    methods (Access = private)
        
        function results = mouseMove(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, handles, varargin)

            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            app.curHandles = handles;

            %% Define colors
            clrswp = handles.tglClrSwp.Value;
            if clrswp
                g1 = 2; g2 = 1;
            else
                g1 = 1; g2 = 2;
            end
            app.CLR1 = handles.colptin(handles.BinClass{1}.groupind(g1),:);
            if ~handles.BinClass{1}.one_vs_all
                app.CLP = handles.colpt;
                app.CLR2 = handles.colptin(handles.BinClass{1}.groupind(g2),:);
            else
                app.CLP = 'o';
                app.CLR2 = rgb('DarkGrey');
            end

            app.AnalysisNoLabel.Text = sprintf("Analysis No. %d: %s", handles.curranal, handles.NM.analysis{1,handles.curranal}.id);
            
            if numel(varargin) ==0
                app.cases = handles.subjects;
                app.SelectsubjectDropDown.Items = app.cases;
                app.SelectsubjectDropDown.ItemsData = [app.cases];
                

                if length(handles.MLIdata.Label.BinResults.Modality)>1
                    app.ModalityDropDown.Visible = 1;
                    app.ModalityDropDownLabel.Visible = 1;
                    modlist = {};
                    for i = 1:length(handles.MLIdata.Label.BinResults.Modality)
                        modlist{i} = handles.NM.analysis{1,handles.curranal}.params.datadescriptor{1,i}.desc;
                    end
                    app.ModalityDropDown.Items = modlist;
                    app.ModalityDropDown.ItemsData = 1:length(modlist);
                    
                else
                    app.ModalityDropDown.Visible = 0;
                    
                end

                app.featnames = handles.NM.featnames{handles.curmodal};
                app.labels = handles.NM.label;
                app.groupnames = handles.NM.groupnames;
                
                app.MLI = handles.MLIdata.Label.BinResults.Modality(1);

                app.curModality = app.MLI.modality_num;
                app.curModalityType = app.MLI.modality_type;

                app.Ys = handles.NM.Y;
                
                DDitemsdataMatChar = app.SelectsubjectDropDown.ItemsData';
                %if isfield(handles, 'curCase')
                curCaseIdx = handles.selCase.Value;
                curCaseID = DDitemsdataMatChar{curCaseIdx};
                app.SelectsubjectDropDown.Value = curCaseID;
                %else 
                 %   curCaseID = app.SelectsubjectDropDown.Value;
                %    
                 %   curCaseIdx = find(strcmp(DDitemsdataMatChar,curCaseID));
                %end
                grouplabel = app.labels;
                groupnames = app.groupnames;
                g = grouplabel(curCaseIdx);
                case_groupname = char(groupnames(g));

                app.TitleLabel.Text = sprintf('Predictive profile of subject #%s. (Group: %s)', curCaseID, case_groupname);

                if app.curModalityType %1 = imaging
                    app.SortfeaturesCheckBox.Visible = 0;
                    app.UIAxes.Visible = 0;
                    app.UIAxes2.Visible = 0;
                    app.UIAxes3.Visible = 1;
                    app.UIAxes4.Visible = 1;
                    app.UIAxes5.Visible = 1;
                    app.stMLI.ParentFig = app.UIFigure;
                    app.stMLI.fig = app.RightPanel;
                    app.stMLI.NMaxes = [ app.UIAxes3 app.UIAxes4 app.UIAxes5];

                    imagingMLI(curCaseIdx, app.MLI, app.stMLI);
                    imagingMLI(curCaseIdx, app.MLI, app.stMLI);
                    %app.PlotButtonPushed(1);
                else
                    app.SortfeaturesCheckBox.Visible = 1;
                    %% Define colors
                    clrswp = handles.tglClrSwp.Value;
                    if clrswp
                        g1 = 2; g2 = 1;
                    else
                        g1 = 1; g2 = 2;
                    end
                    app.CLR1 = handles.colptin(handles.BinClass{1}.groupind(g1),:);
                    if ~handles.BinClass{1}.one_vs_all
                        app.CLP = handles.colpt;
                        app.CLR2 = handles.colptin(handles.BinClass{1}.groupind(g2),:);
                    else
                        app.CLP = 'o';
                        app.CLR2 = rgb('DarkGrey');
                    end

                    app.UIAxes.Visible = 1;
                    app.UIAxes2.Visible = 1;
                    app.UIAxes3.Visible = 0;
                    app.UIAxes4.Visible = 0;
                    app.UIAxes5.Visible = 0;
                    

                    barMLI(curCaseIdx, app.MLI, ...
                        app.featnames, ...
                        app.Ys{1,app.curModality}, ...
                        app.SortfeaturesCheckBox.Value, ...
                        app.UIAxes, ...
                        app.UIAxes2, ...
                        app.CLR1, ...
                        app.CLR2);
                end


            else
                app.PlotButton.Visible = 0;
                app.SortfeaturesCheckBox.Visible = 0;
                app.SelectsubjectDropDownLabel.Visible = 0;
                app.SelectsubjectDropDown.Visible = 0;
                app.ModalityDropDown.Visible = 0;
                app.curModality = app.MLI.modality_num;
                app.curModalityType = app.MLI.modality_type;
                app.cases = handles.subjects;
                app.SelectsubjectDropDown.Items = app.cases;
                app.SelectsubjectDropDown.ItemsData = [app.cases];
                curCaseIdx = varargin{1};
                DDitemsdataMatChar = app.SelectsubjectDropDown.ItemsData';
                curCaseID = DDitemsdataMatChar(curCaseIdx);

                grouplabel = handles.NM.label;
                groupnames = handles.NM.groupnames;
                g = grouplabel(curCaseIdx);
                case_groupname = char(groupnames(g));
                app.TitleLabel.Text = sprintf('Predictive profile of subject #%s. (Group: %s)', string(curCaseID), case_groupname);

                if app.curModalityType %1 = imaging
                    app.UIAxes.Visible = 0;
                    app.UIAxes2.Visible = 0;
                    app.UIAxes3.Visible = 1;
                    app.UIAxes4.Visible = 1;
                    app.UIAxes5.Visible = 1;
                    app.stMLI.ParentFig = app.UIFigure;
                    app.stMLI.fig = app.RightPanel;
                    app.stMLI.NMaxes = [ app.UIAxes3 app.UIAxes4 app.UIAxes5];

                    imagingMLI(curCaseIdx, app.MLI, app.stMLI);
                else
                    app.UIAxes.Visible = 1;
                    app.UIAxes2.Visible = 1;
                    app.UIAxes3.Visible = 0;
                    app.UIAxes4.Visible = 0;
                    app.UIAxes5.Visible = 0;

                    barMLI(curCaseIdx, handles.MLIdata.Label.BinResults.Modality(1), ...
                        handles.NM.featnames, ...
                        handles.NM.Y{1,app.curModality}, ...
                        app.SortfeaturesCheckBox.Value, ...
                        app.UIAxes, ...
                        app.UIAxes2, ...
                        app.CLR1, ...
                        app.CLR2);
                end

            end
       
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            %cla(app.UIAxes3);
            %cla(app.UIAxes4);
            %cla(app.UIAxes5);
           
            curCaseID = app.SelectsubjectDropDown.Value;
            DDitemsdataMatChar = app.SelectsubjectDropDown.ItemsData';
            curCaseIdx = find(strcmp(DDitemsdataMatChar,curCaseID));
 
        
            if app.ModalityDropDown.Visible == 1
                modIDX = app.ModalityDropDown.Value; 
                app.MLI = app.curHandles.MLIdata.Label.BinResults.Modality(modIDX);
            end
            app.curModality = app.MLI.modality_num;
            app.curModalityType = app.MLI.modality_type;
           
            grouplabel = app.labels;
            g = grouplabel(curCaseIdx);
            groupnames = app.groupnames;
            case_groupname = char(groupnames(g));
            app.featnames = app.curHandles.NM.featnames{app.curModality};
            %app.UIAxes.Title.String = sprintf('Predictive profile of subject #%s (Group: %s)', curCaseID, case_groupname);
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = 'on';
            app.TitleLabel.Text = sprintf('Predictive profile of subject #%s. (Group: %s)', curCaseID, case_groupname);
            if app.curModalityType %1 = imaging
                app.UIAxes.Visible = 0;
                app.UIAxes2.Visible = 0;
                app.UIAxes3.Visible = 1;
                app.UIAxes4.Visible = 1;
                app.UIAxes5.Visible = 1;
                

                imagingMLI(curCaseIdx, app.MLI, app.stMLI);

            else
                app.UIAxes.Visible = 1;
                app.UIAxes2.Visible = 1;
                app.UIAxes3.Visible = 0;
                app.UIAxes4.Visible = 0;
                app.UIAxes5.Visible = 0;
                barMLI(curCaseIdx, ...
                    app.MLI, ...
                    app.featnames, ...
                    app.Ys{1,app.curModality}, ...
                    app.SortfeaturesCheckBox.Value, ...
                    app.UIAxes, ...
                    app.UIAxes2, ...
                    app.CLR1, ...
                    app.CLR2);
            end
        end

        % Drop down opening function: SelectsubjectDropDown
        function SelectsubjectDropDownOpening(app, event)
            
        end

        % Value changed function: SelectsubjectDropDown
        function SelectsubjectDropDownValueChanged(app, event)
           
        end

        % Value changed function: ModalityDropDown
        function ModalityChanged(app, event)
%             modIDX= app.ModalityDropDown.Value;
%             app.MLI = app.curHandles.MLIdata.Label.BinResults.Modality(modIDX);
%             app.curModality = app.MLI.modality_num;
%             app.curModalityType = app.MLI.modality_type;


        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {807, 807};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {215, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectsubjectDropDownLabel = uilabel(app.LeftPanel);
            app.SelectsubjectDropDownLabel.Text = 'Select subject';
            app.SelectsubjectDropDown = uidropdown(app.LeftPanel);
            app.SelectsubjectDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectsubjectDropDownOpening, true);
            app.SelectsubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectsubjectDropDownValueChanged, true);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SortfeaturesCheckBox = uicheckbox(app.LeftPanel);
            app.SortfeaturesCheckBox.Text = 'Sort features';
            app.ModalityDropDownLabel = uilabel(app.LeftPanel);
            app.ModalityDropDownLabel.Visible = 'off';
            app.ModalityDropDownLabel.Text = 'Modality';
            app.ModalityDropDown = uidropdown(app.LeftPanel);
            app.ModalityDropDown.ValueChangedFcn = createCallbackFcn(app, @ModalityChanged, true);
            app.ModalityDropDown.Visible = 'off';
            app.AnalysisNoLabel = uilabel(app.LeftPanel);
            app.AnalysisNoLabel.Text = 'Analysis No.';
            app.BinaryclassifierDropDownLabel = uilabel(app.LeftPanel);
            app.BinaryclassifierDropDownLabel.Visible = 'off';
            app.BinaryclassifierDropDownLabel.Text = 'Binary classifier';
            app.BinaryclassifierDropDown = uidropdown(app.LeftPanel);
            app.BinaryclassifierDropDown.Visible = 'off';
            app.LabelDropDownLabel = uilabel(app.LeftPanel);
            app.LabelDropDownLabel.Visible = 'off';
            app.LabelDropDown = uidropdown(app.LeftPanel);
            app.LabelDropDown.Visible = 'off';
            app.AnalysisDropDownLabel = uilabel(app.LeftPanel);
            app.AnalysisDropDownLabel.Visible = 'off';
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.LeftPanel);
            app.AnalysisDropDown.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'parula')
            app.UIAxes2 = uiaxes(app.RightPanel);
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'parula')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Transverse')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            colormap(app.UIAxes3, 'parula')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Coronal')
            colormap(app.UIAxes4, 'parula')
            app.UIAxes5 = uiaxes(app.RightPanel);
            title(app.UIAxes5, 'Sagittal')
            colormap(app.UIAxes5, 'parula')
            app.TitleLabel = uilabel(app.RightPanel);
            app.TitleLabel.Text = 'Title';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMLI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end