classdef appSpiderPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIMapperUIFigure             matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ConfigPanel                   matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        AnalysisTab                   matlab.ui.container.Tab
        AnalysesListBox               matlab.ui.control.ListBox
        AnalysesListBoxLabel          matlab.ui.control.Label
        PatternDropDown               matlab.ui.control.DropDown
        PatternDropDownLabel          matlab.ui.control.Label
        OutputresultsPanel            matlab.ui.container.Panel
        SelectdirButton               matlab.ui.control.Button
        OutputPathEditField           matlab.ui.control.EditField
        ThresholdingPanel             matlab.ui.container.Panel
        OperatorLabel                 matlab.ui.control.Label
        MinimumVoxPercEditField       matlab.ui.control.NumericEditField
        MinimumLabel                  matlab.ui.control.Label
        ThresholdtypeDropDown         matlab.ui.control.DropDown
        Threshold2EditField           matlab.ui.control.NumericEditField
        Threshold2EditFieldLabel      matlab.ui.control.Label
        Threshold1EditField           matlab.ui.control.NumericEditField
        Threshold1EditFieldLabel      matlab.ui.control.Label
        TwothresholdmodeCheckBox      matlab.ui.control.CheckBox
        AtlasingPanel                 matlab.ui.container.Panel
        ROInameDropDown               matlab.ui.control.DropDown
        ROInameDropDownLabel          matlab.ui.control.Label
        ROIIDDropDown                 matlab.ui.control.DropDown
        ROIIDDropDownLabel            matlab.ui.control.Label
        ReadtableheaderButton         matlab.ui.control.Button
        DelimiterDropDown             matlab.ui.control.DropDown
        DelimiterDropDownLabel        matlab.ui.control.Label
        SelectROIButton               matlab.ui.control.Button
        ROIfileEditField              matlab.ui.control.EditField
        ROIfileEditFieldLabel         matlab.ui.control.Label
        SelectAtlasButton             matlab.ui.control.Button
        AtlasfileEditField            matlab.ui.control.EditField
        AtlasfileEditFieldLabel       matlab.ui.control.Label
        PlottingTab                   matlab.ui.container.Tab
        SpiderplotconfigurationPanel  matlab.ui.container.Panel
        MinorgridlinesSpinner         matlab.ui.control.Spinner
        MinorgridlinesSpinnerLabel    matlab.ui.control.Label
        MinorgridCheckBox             matlab.ui.control.CheckBox
        AxesprecisionSpinner          matlab.ui.control.Spinner
        AxesprecisionSpinnerLabel     matlab.ui.control.Label
        AxesintervalSpinner           matlab.ui.control.Spinner
        AxesintervalSpinnerLabel      matlab.ui.control.Label
        MarkersizeSpinner             matlab.ui.control.Spinner
        MarkersizeSpinnerLabel        matlab.ui.control.Label
        MarkerDropDown                matlab.ui.control.DropDown
        MarkerDropDownLabel           matlab.ui.control.Label
        MaximumEditField              matlab.ui.control.NumericEditField
        MaximumEditFieldLabel         matlab.ui.control.Label
        MinimumEditField              matlab.ui.control.NumericEditField
        MinimumEditFieldLabel         matlab.ui.control.Label
        LinewidthSpinner              matlab.ui.control.Spinner
        LinewidthSpinnerLabel         matlab.ui.control.Label
        LinecolorEditField            matlab.ui.control.NumericEditField
        LinecolorEditFieldLabel       matlab.ui.control.Label
        LegendfontsizeSpinner         matlab.ui.control.Spinner
        LabelfontsizeSpinner_2Label   matlab.ui.control.Label
        AxesfontsizeSpinner           matlab.ui.control.Spinner
        AxesfontsizeSpinnerLabel      matlab.ui.control.Label
        LabelfontsizeSpinner          matlab.ui.control.Spinner
        LabelfontsizeSpinnerLabel     matlab.ui.control.Label
        AxesdirectionDropDown         matlab.ui.control.DropDown
        AxesdirectionDropDownLabel    matlab.ui.control.Label
        SelectmeasureDropDown         matlab.ui.control.DropDown
        SelectmeasureDropDownLabel    matlab.ui.control.Label
        Tree                          matlab.ui.container.CheckBoxTree
        Node                          matlab.ui.container.TreeNode
        Node2                         matlab.ui.container.TreeNode
        Node3                         matlab.ui.container.TreeNode
        Node4                         matlab.ui.container.TreeNode
        PlotButton                    matlab.ui.control.Button
        AnalyzeButton                 matlab.ui.control.Button
        PlotPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        pimg
        patterndesc
        patternfields
        patternnames
        visdata
        colors
        markers
        handles
        atlas % atlas image
        roiList % atlas regions csv
        roiT % atlas regions table
        mniIDX1
        mniIDX2
        options
        meas
        measind
        vROI
        tROI
        yROI
        yT
    end
    
    methods (Access = private)
        
        function [popuplist, popupitems] = PopulatePatternDropDown(app)
            analind = app.AnalysesListBox.Value;
            nA = numel(analind);
            patterncounter = zeros(1, numel(app.patternfields));
            for i=1:nA
                v = app.visdata{i};
                fldnames = fieldnames(v{1});
                idx = ismember(app.patternfields, fldnames);
                patterncounter(idx) = patterncounter(idx)+1;
            end
            patternbool = patterncounter == numel(analind);
            popuplist = app.patterndesc(patternbool);
            popupitems = app.patternfields(patternbool);
        end
        
        function [app, popuplist, popupitems] = PopulateAnalysesDropDown(app)
            AS = nk_GetAnalysisStatus(app.handles.NM);
            idx = AS.analyses_visualized & ~cellfun(@isempty,AS.analyses_imaging);
            f_idx = find(idx);
            popuplist = cell(1, numel(f_idx));
            for i=1:numel(f_idx)
                popuplist{i} = sprintf('%g: %s', f_idx(i), app.handles.NM.analysis{f_idx(i)}.id);
                app.visdata{i} = app.handles.NM.analysis{f_idx(i)}.visdata;
            end
            popupitems = f_idx;
        end
        
        function app = WritePatterns2Disk(app)

            app.options.name = fullfile(app.OutputPathEditField.Value,'ROImapper');
            if ~exist(app.options.name,"dir"), mkdir(app.options.name); end
            pattern_field = app.PatternDropDown.Value;
            cnt=1;
            % No. of classes
            if strcmp(app.handles.NM.analysis{1}.params.modeflag,'classification')
                nC = numel(app.handles.NM.analysis{1}.params.cv.class{1,1});
                modestr = 'class'; modeflag=1;
            else
                nC = 1;
                modestr = 'regr'; modeflag=2;
            end
            nA = numel(app.AnalysesListBox.Value);
            for i=1:nA
                % Analysis index
                idx_A = app.AnalysesListBox.Value(i);
                % No. of Modalities and labels
                [nF, nL] = size(app.visdata{i});
                if nA>1
                    pattern_field2 = sprintf('%s_A%g', pattern_field, idx_A);
                else
                    pattern_field2 = sprintf('%s', pattern_field);
                end
                app.options.tree.analysis(i).name = pattern_field2;
                app.options.tree.analysis(i).idx = idx_A;
                app.options.tree.analysis(i).checked = true;
                app.options.tree.analysis(i).searchterm = pattern_field2;
                for j=1:nF
                    idx_F = app.visdata{i}{j,1}.params.varind;
                    brainmask   = app.handles.NM.brainmask{idx_F};
                    badcoords   = app.handles.NM.badcoords{idx_F};
                    labelthr    = app.handles.NM.datadescriptor{idx_F}.threshval;
                    labelop     = app.handles.NM.datadescriptor{idx_F}.threshop;
                    if nF>1
                        pattern_field3 = sprintf('%s_M%g', pattern_field2, idx_F);
                    else
                        pattern_field3 = sprintf('%s', pattern_field2);
                    end
                    app.options.tree.analysis(i).modality(j).name = sprintf('M%g',idx_F);
                    app.options.tree.analysis(i).modality(j).idx = idx_F;
                    app.options.tree.analysis(i).modality(j).checked = true;
                    app.options.tree.analysis(i).modality(j).searchterm = pattern_field3;
                    for l=1:nC
                        if modeflag==1
                            clstr = regexprep(app.handles.NM.analysis{1}.params.cv.class{1,1}{l}.groupdesc,' ', '-');
                            pattern_field4 = sprintf('%s_%s', pattern_field3, clstr);
                        else
                            pattern_field4 = sprintf('%s', pattern_field3);
                        end
                        app.options.tree.analysis(i).modality(j).(modestr)(l).name = clstr; 
                        app.options.tree.analysis(i).modality(j).(modestr)(l).checked = true;
                        app.options.tree.analysis(i).modality(j).(modestr)(l).searchterm = pattern_field4;
                        for k=1:nL
                            if nL>1
                                patternname = sprintf('%s_L%g', pattern_field4, k);
                                labelname = sprintf('L%g: %s', k, app.handles.NM.labelnames{k});
                            else
                                patternname = sprintf('%s', pattern_field4);
                                labelname = sprintf('L%g', k);
                            end
                       
                            img = app.visdata{i}{j,k}.(pattern_field){l};
                            app.options.pimg{cnt} = fullfile(app.options.name, patternname);
                            app.options.pattern_names{cnt} = patternname;
                            nk_WriteVol(img, app.options.pimg{cnt}, 2, brainmask, badcoords, labelthr, char(labelop));
                            app.options.pimg{cnt} = [app.options.pimg{cnt} '.nii'];
                            % Prepare plot tree
                            app.options.tree.analysis(i).modality(j).(modestr)(l).label(k).name = labelname;
                            app.options.tree.analysis(i).modality(j).(modestr)(l).label(k).searchterm = patternname; 
                            app.options.tree.analysis(i).modality(j).(modestr)(l).label(k).visible = true; 
                            app.options.tree.analysis(i).modality(j).(modestr)(l).label(k).color = app.colors(cnt,:);
                            app.options.tree.analysis(i).modality(j).(modestr)(l).label(k).alpha = 0.25;
                            cnt=cnt+1;
                        end
                    end
                end
            end
            app.pimg = char(app.options.pimg);
        end
        
        function app = PopulateTreeControl(app)
            if strcmp(app.handles.NM.analysis{1}.params.modeflag,'classification')
                modestr = 'class'; modeflag=1;
            else
                modestr = 'regr'; modeflag=2;
            end

            app.TabGroup.SelectedTab = app.PlottingTab;
            delete(app.Tree.Children);

            for i=1:numel(app.options.tree.analysis)
                app.options.tree.analysis(i).node = uitreenode(app.Tree, "Text", app.options.tree.analysis(i).name);
                nF = numel(app.options.tree.analysis(i).modality);
                nd = app.options.tree.analysis(i).node;
                for j=1:nF
                    if nF>1
                        app.options.tree.analysis(i).modality(j).node = ...
                            uitreenode(nd, ...
                            "Text",app.options.tree.analysis(i).modality(j).name); 
                        ndl = app.options.tree.analysis(i).modality(j).node;
                    else
                        ndl = nd;
                    end
                    nK = numel(app.options.tree.analysis(i).modality(j).(modestr));
                    for k=1:nK
                        if nK>1
                            app.options.tree.analysis(i).modality(j).(modestr)(k).node = ...
                                uitreenode(ndl, ...
                                "Text",app.options.tree.analysis(i).modality(j).(modestr)(k).name); 
                            ndk =  app.options.tree.analysis(i).modality(j).(modestr)(k).node;
                        else
                            ndk = ndl;
                        end
                        nL = numel(app.options.tree.analysis(i).modality(j).(modestr)(k).label);
                        for l=1:nL
                            if nL>1
                                app.options.tree.analysis(i).modality(j).(modestr)(k).label(l).node = ...
                                    uitreenode(ndk, ...
                                    "Text",app.options.tree.analysis(i).modality(j).(modestr)(k).label(l).name); 
                            end
                        end
                    end
                end
            end
            app.Tree.CheckedNodes = app.Tree.Children;
        end
        
        function app = ExtractROIColumns(app)
            if strcmp(app.handles.NM.analysis{1}.params.modeflag,'classification')
                modestr = 'class'; modeflag=1;
            else
                modestr = 'regr'; modeflag=2;
            end
            cnt=1; suffix = '';
            switch app.SelectmeasureDropDown.Value
                case 1 
                    prefix = 'K_ROI_'; suffix = '[%]';
                case 2
                    prefix = 'Mean_';
                case 3
                    prefix = 'Min_';
                case 4
                    prefix = 'Max_';
            end

            nA = numel(app.options.tree.analysis); nChecked = numel(app.Tree.CheckedNodes); fnodes = false(nChecked,1); cnt=1;
            searchterms = []; colors = [];
            while sum(fnodes) < nChecked
                idx = find(~fnodes); 
                n = app.Tree.CheckedNodes(idx(1));
                for i=1:nA
                    if isequal(n,app.options.tree.analysis(i).node)
                        searchterms{cnt} = app.options.tree.analysis(i).searchterm;
                        fnodes(idx(1)) = true;
                        fnodes = FindNodeInTree(app, app.options.tree.analysis(i), fnodes);
                        break
                    else
                        nF =  numel(app.options.tree.analysis(i).modality); 
                        for j=1:nF
                           if isfield(app.options.tree.analysis(i).modality(j),'node') && ...
                                   isequal(n,app.options.tree.analysis(i).modality(j).node)
                                searchterms{cnt} = app.options.tree.analysis(i).modality(j).searchterm;
                                fnodes(idx(1)) = true; cnt=cnt+1;
                                fnodes = FindNodeInTree(app, app.options.tree.analysis(i).modality(j), fnodes);
                                break
                           else
                                nK = numel(app.options.tree.analysis(i).modality(j).(modestr));
                                for k=1:nK
                                    if isfield(app.options.tree.analysis(i).modality(j).(modestr),'node') && ...
                                            isequal(n, app.options.tree.analysis(i).modality(j).(modestr)(k).node)
                                        searchterms{cnt} = app.options.tree.analysis(i).modality(j).(modestr)(k).searchterm;
                                        fnodes(idx(1)) = true; cnt=cnt+1;
                                        fnodes = FindNodeInTree(app, app.options.tree.analysis(i).modality(j).(modestr)(k), fnodes);
                                        break
                                    else
                                        nL = numel(app.options.tree.analysis(i).modality(j).(modestr)(k).label);
                                        for l=1:nL
                                            if isfield(app.options.tree.analysis(i).modality(j).(modestr)(k).label(l),'node') && ...
                                                    isequal(n, app.options.tree.analysis(i).modality(j).(modestr)(k).label(l).node)
                                                searchterms{cnt} = app.options.tree.analysis(i).modality(j).(modestr)(k).label(l).searchterm;
                                                fnodes(idx(1)) = true; cnt=cnt+1;
                                                break
                                            end
                                        end
                                    end
                                end
                           end
                        end
                    end
                end
            end 
            app.options.tROI_columns=[]; 
            app.options.tROI_colors=[];
            app.options.tROI_legend=[];
            cnti=1;
            for i=1:numel(searchterms)
                i_search = [prefix searchterms{i} '.*' suffix];
                idx = find(~cellfun(@isempty,regexp(app.tROI.Properties.VariableNames', i_search)));
                app.options.tROI_columns = [app.options.tROI_columns; idx];
                app.options.tROI_legend = [app.options.tROI_legend ; app.tROI.Properties.VariableNames(idx)' ];
                app.options.tROI_colors = [app.options.tROI_colors; app.colors(cnti:cnti+numel(idx)-1,:)];
                cnti = cnti + numel(idx); 
            end
        end
        
        function fnodes = FindNodeInTree(app, treenode, fnodes)
            nfnd = find(~fnodes);
            for z=1:numel(nfnd)
                if ~fnodes(nfnd(z))
                    fnd = StructFindX(treenode, app.Tree.CheckedNodes(nfnd(z)));
                    if ~isempty(fnd), fnodes(nfnd(z))=true; end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, handles)
            
            % Start with definitions
            app.patterndesc = {'Feature weights [Overall Mean (StErr)]', ...
                        'Feature weights [Grand Mean (StErr)]', ...
                        'CV-ratio of feature weights [Overall Mean]', ...
                        'CV-ratio of feature weights [Grand Mean]', ...
                        'Feature selection probability [Overall Mean]', ...
                        'Probability of feature reliability (95%-CI) [Grand Mean]', ...
                        'Sign-based consistency', ...
                        'Sign-based consistency (Z score)', ...
                        'Sign-based consistency -log10(P value)', ...
                        'Sign-based consistency -log10(P value, FDR)', ...
                        'Spearman correlation [Grand Mean]', ...
                        'Pearson correlation [Grand Mean]', ...
                        'Spearman correlation -log10(P value) [Grand Mean]', ...
                        'Pearson correlation -log10(P value) [Grand Mean]', ...
                        'Spearman correlation -log10(P value, FDR) [Grand Mean]', ...
                        'Pearson correlation -log10(P value, FDR) [Grand Mean]', ...
                        'Permutation-based -log10(P value) [Grand Mean]', ...
                        'Permutation-based -log10(P value, FDR) [Grand Mean]', ...
                        'Permutation-based Z Score [Grand Mean]', ...
                        'Analytical -log10(P Value) for Linear SVM [Grand Mean]', ...
                        'Analytical -log10(P Value, FDR) for Linear SVM [Grand Mean]'};

            app.patternfields = {'MEAN', ...
                        'MEAN_CV2', ...
                        'CVRatio', ...
                        'CVRatio_CV2', ...
                        'FeatProb', ...
                        'Prob_CV2', ...
                        'SignBased_CV2', ...
                        'SignBased_CV2_z', ...
                        'SignBased_CV2_p_uncorr', ...
                        'SignBased_CV2_p_fdr', ...
                        'Spearman_CV2', ...
                        'Pearson_CV2', ...
                        'Spearman_CV2_p_uncorr', ...
                        'Pearson_CV2_p_uncorr', ...
                        'Spearman_CV2_p_fdr', ...
                        'Pearson_CV2_p_fdr', ...
                        'PermProb_CV2', ...
                        'PermProb_CV2_FDR_PVAL', ...
                        'PermZ_CV2', ...
                        'Analytical_p', ...
                        'Analyitcal_p_fdr'};

            app.patternnames = {'FeatWeights_overallM', ...
                        'FeatWeights_grandM', ...
                        'CV_featWeights_overallM', ...
                        'CV_featWeights_grandM', ...
                        'ProbFeat_overallM', ...
                        'ProbFeatRel95_grandM', ...
                        'SignBsdConsistency', ....
                        'SignBsdConsistency_ZScore', ...
                        'SignBsdConsistency_log10Pval', ...
                        'SignBsdConsistency_log10Pval_FDR', ...
                        'Spearman_grandM', ...
                        'Pearson_grandM', ...
                        'Spearman_grandM_log10Pval', ...
                        'Pearson_grandM_log10Pval', ...
                        'Spearman_grandM_log10Pval_FDR', ...
                        'Pearson_grandM_log10Pval_FDR', ...
                        'Perm_log10Pval', ...
                        'Perm_log10Pval_FDR', ...
                        'Perm_Z', ...
                        'AnalyticalP_log10Pval', ...
                        'AnalyticalP_log10Pval_FDR'};
            app.markers = {'none','o','*','s','d'};
            app.colors = getNMcolors;
            app.handles = handles; 
            % for now, this is the default atlas
            app.atlas = sprintf('%s/interpretable/anatomical/AAL3.nii', handles.NM.defs.paths);
            app.AtlasfileEditField.Value = app.atlas;
            app.roiList = sprintf('%s/interpretable/anatomical/AAL3.csv', handles.NM.defs.paths);
            app.ROIfileEditField.Value = app.roiList;
            app.roiT = readtable(app.ROIfileEditField.Value, 'Delimiter', ";");
            app.ROIIDDropDown.Items = app.roiT.Properties.VariableNames;
            app.ROIIDDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
            app.ROIIDDropDown.Value=1;
            app.ROInameDropDown.Items = app.roiT.Properties.VariableNames;
            app.ROInameDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
            app.ROInameDropDown.Value=2;
            
            [app, app.AnalysesListBox.Items, app.AnalysesListBox.ItemsData] = PopulateAnalysesDropDown(app);
            [app.PatternDropDown.Items, app.PatternDropDown.ItemsData] = PopulatePatternDropDown(app);

            % user input
            app.options = struct;
            app.options.vxcheck = 1;
            app.options.thresh = app.Threshold1EditField.Value;
            app.options.typthresh = str2double(app.ThresholdtypeDropDown.Value);
            app.options.minext = app.MinimumVoxPercEditField.Value;
            app.options.saving = 1;

            app.OutputPathEditField.Value = handles.NM.analysis{1,handles.curranal}.rootdir; % does rootdir exist?
            if isfield(handles,'selVisMeas')
                app.measind = handles.selVisMeas.Value;
                app.meas = handles.selVisMeas.String;
                app.PatternDropDown.Value = app.patternfields{strcmp(app.patterndesc, app.meas{app.measind})};
            else
                app.measind = 1;
                app.meas = app.patterndesc{1};
            end
            app.options.pattern_index = strcmp(app.patterndesc, app.meas{app.measind});
            app.options.pattern_name = app.patternnames{app.options.pattern_index};
            app.options.pattern_field = app.patternfields{app.options.pattern_index};
            app.options.data = [];
            app.options.compute_voxelstats = 0;
            app.TwothresholdmodeCheckBox.Value = 0;

            %spider_plot(P, ...
%                 'axesLabels', rois.Var2,...
%                 'axesLimits', [zeros(1,y); 100*ones(1,y)], ...
%                 'axesInterval', 1, ...
%                 'axesLabelsEdge','none', ...
%                 'axesDisplay', 'one', ...
%                 'axesLabelsOffset', 0.05, ...
%                 'axesFontSize', 12,...
%                 'labelFontSize', 9, ...
%                 'lineWidth',2,...
%                 'fillOption', 'on',...
%                 'markerSize', 18,...
%                 'axesHorzAlign', 'quadrant', ...
%                 'axesVertAlign', 'quadrant', ...
%                 'axesLabelsRotate', 'on', ...
%                 'axesDirection', 'normal', ...
%                 'axesPrecision',0,...
%                 'axesZero','on',...
%                 'axesLabelsRotate','on',...
%                 'axesOffset',1, ...
%                 'axesobject', app.UIAxes);

        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)

            app.options = struct;
            app.options.vxcheck = 1;
            if app.TwothresholdmodeCheckBox.Value
                app.options.thresh = [app.Threshold1EditField.Value app.Threshold2EditField.Value];
            else
                app.options.thresh = app.Threshold1EditField.Value;
            end
            app.options.typthresh = str2double(app.ThresholdtypeDropDown.Value);
            app.options.minext = app.MinimumVoxPercEditField.Value;
            app.options.saving = 1;
            app.options.pattern_index = strcmp(app.patternfields, app.PatternDropDown.Value);
            app.options.pattern_name = app.patternnames{app.options.pattern_index};
            app.options.pattern_field = app.patternfields{app.options.pattern_index};
            app.options.data = [];
            app.options.compute_voxelstats = 0;
            
            % First write patterns into output directory
            app = WritePatterns2Disk(app);
            
            % Then, parcellate and analyze patterns according to user
            % settings
            [app.vROI, app.tROI, app.yROI, app.yT] = nk_ROImapper(app.pimg, ...
                [], ...
                app.atlas, ...
                app.roiList, ...
                app.ROIIDDropDown.Value, ...
                app.ROInameDropDown.Value, ...
                app.options);

            app.PlotButton.Enable="on";
            app.UIAxes.Visible="off";
            drawnow
        end

        % Value changed function: TwothresholdmodeCheckBox
        function TwothresholdmodeCheckBoxValueChanged(app, event)
            value = app.TwothresholdmodeCheckBox.Value;
            if value
                 app.ThresholdtypeDropDown.Items = {'<|>','<=|>=','>|<','>=|<='};
                 app.ThresholdtypeDropDown.ItemsData = 1:4;
                 app.Threshold2EditField.Enable = "on";
                 app.Threshold2EditFieldLabel.Enable = "on";
            else
                 app.ThresholdtypeDropDown.Items = {'<','<=','>','>=','=='};
                 app.ThresholdtypeDropDown.ItemsData = 1:5;
                 app.Threshold2EditField.Enable = "off";
                 app.Threshold2EditFieldLabel.Enable = "off";
            end
            drawnow
        end

        % Button pushed function: SelectAtlasButton
        function SelectAtlasButtonPushed(app, event)
            if isempty(app.AtlasfileEditField.Value)
                defatlas = fullfile(app.handles.NM.defs.paths,'interpretable','anatomical','aal3.nii');
            else
                defatlas = app.AtlasfileEditField.Value;
            end
            [atlas_file,atlas_path] = uigetfile({'*.nii','NIfTI-files (*.nii)'; ...
                '*.img','NIftI/ANALYZE images (*.img)'},'Select atlas image file', defatlas);
            app.atlas = fullfile(atlas_path,atlas_file);
            app.AtlasfileEditField.Value = app.atlas;
            app.AtlasfileEditField.Enable = "on";
            drawnow
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            if isempty(app.ROIfileEditField.Value)
                defroi = fullfile(app.handles.NM.defs.paths,'interpretable','anatomical','aal3.csv');
            else
                defroi = app.ROIfileEditField.Value;
            end
            [roi_file, roi_path] = uigetfile({'*.csv','CSV-files (*.csv)'; ...
                '*.txt','Text files (*.txt)'},'Select atlas ROI descriptor file', defroi);
            app.roiList = fullfile(roi_path,roi_file);
            app.ROIfileEditField.Value = app.roiList;
            app.ROIfileEditField.Enable = "on";
            app.DelimiterDropDown.Enable = "on";
            app.ReadtableheaderButton.Enable = "on";
            drawnow
        end

        % Button pushed function: ReadtableheaderButton
        function ReadtableheaderButtonPushed(app, event)
            delimiters = {';',',','space','tab'};
            value = app.DelimiterDropDown.Value;
            try
                 app.roiT = readtable(app.ROIfileEditField.Value, 'Delimiter', delimiters{value});
            catch ERR
                errordlg(sprintf('The atlas descriptor file %s could not be read!\nError: %s\nCheck your settings!', ...
                    app.ROIfileEditField.Value, ERR.message), 'Spider plot');
            end
            if ~isempty(app.roiT)
                if numel(app.roiT.Properties.VariableNames)>1
                        app.ROIIDDropDown.Enable="on";
                        app.ROInameDropDown.Enable="on";
                        app.ROIIDDropDown.Items = app.roiT.Properties.VariableNames;
                        app.ROIIDDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
                        app.ROInameDropDown.Items = app.roiT.Properties.VariableNames;
                        app.ROInameDropDown.ItemsData = 1:numel(app.ROIIDDropDown.Items); 
                else
                    app.ROIIDDropDown.Enable="off";
                    app.ROInameDropDown.Enable="off";
                end
            end
            drawnow
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
      
            app = PopulateTreeControl(app);
            app = ExtractROIColumns(app);
            cla(app.UIAxes);
            app.options.spider.linewidth = app.LinewidthSpinner.Value;
            app.options.spider.legendfontsize = app.LegendfontsizeSpinner.Value;
            app.options.spider.axesfontsize = app.AxesfontsizeSpinner.Value;
            app.options.spider.labelfontsize = app.LabelfontsizeSpinner.Value;
            app.options.spider.axesdirection = app.AxesdirectionDropDown.Value;
            app.options.spider.axesprecision = app.AxesprecisionSpinner.Value;
            app.options.spider.max = app.MaximumEditField.Value;
            app.options.spider.min = app.MinimumEditField.Value;
            app.options.spider.axesintval = app.AxesintervalSpinner.Value;
            app.options.spider.marker = app.markers{app.MarkerDropDown.Value};
            app.options.spider.markersize = app.MarkersizeSpinner.Value;
            if app.MinorgridCheckBox.Value
                app.options.spider.minorgrid = 'on';
            else
                app.options.spider.minorgrid = 'off';
            end
            app.options.spider.minorgridintval = app.MinorgridlinesSpinner.Value;

            plot_spider_plot(app.tROI, app.options, app.UIAxes);
            app.UIAxes.Visible="on";
            drawnow
        end

        % Button pushed function: SelectdirButton
        function SelectdirButtonPushed(app, event)
            app.OutputPathEditField.Value = uigetdir(app.handles.NM.analysis{app.handles.curranal}.rootdir);
        end

        % Value changed function: DelimiterDropDown
        function DelimiterDropDownValueChanged(app, event)
            
        end

        % Value changed function: Threshold1EditField
        function Threshold1EditFieldValueChanged(app, event)
            
        end

        % Value changed function: AnalysesListBox
        function AnalysesListBoxValueChanged(app, event)
            [app.PatternDropDown.Items, app.PatternDropDown.ItemsData] = PopulatePatternDropDown(app);
            app.options.analyses = app.AnalysesListBox.Value;
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app = ExtractROIColumns(app);
            cla(app.UIAxes);
            plot_spider_plot(app.tROI, app.options, app.UIAxes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ROIMapperUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {795, 795};
                app.GridLayout.ColumnWidth = {'1x'};
                app.PlotPanel.Layout.Row = 2;
                app.PlotPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {232, '1x'};
                app.PlotPanel.Layout.Row = 1;
                app.PlotPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIMapperUIFigure = uifigure('Visible', 'off');
            app.ROIMapperUIFigure.Name = 'ROI Mapper';
            app.ROIMapperUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ROIMapperUIFigure);
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ConfigPanel.Tooltip = {''};
            app.ConfigPanel.Title = 'Configuration';
            app.AnalyzeButton = uibutton(app.ConfigPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.PlotButton = uibutton(app.ConfigPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.TabGroup = uitabgroup(app.ConfigPanel);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AtlasingPanel = uipanel(app.AnalysisTab);
            app.AtlasingPanel.Title = 'Atlasing';
            app.AtlasfileEditFieldLabel = uilabel(app.AtlasingPanel);
            app.AtlasfileEditFieldLabel.Enable = 'off';
            app.AtlasfileEditFieldLabel.Text = 'Atlas file';
            app.AtlasfileEditField = uieditfield(app.AtlasingPanel, 'text');
            app.AtlasfileEditField.Enable = 'off';
            app.AtlasfileEditField.Tooltip = {'path to parcellation image <char>'};
            app.SelectAtlasButton = uibutton(app.AtlasingPanel, 'push');
            app.SelectAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAtlasButtonPushed, true);
            app.SelectAtlasButton.Text = 'Select Atlas';
            app.ROIfileEditFieldLabel = uilabel(app.AtlasingPanel);
            app.ROIfileEditFieldLabel.Enable = 'off';
            app.ROIfileEditFieldLabel.Text = 'ROI file';
            app.ROIfileEditField = uieditfield(app.AtlasingPanel, 'text');
            app.ROIfileEditField.Enable = 'off';
            app.ROIfileEditField.Tooltip = {'path to csv or excel file with parcellation info, which should contain the the id column indexing parcellations '; 'in the parcellation image <char>'};
            app.SelectROIButton = uibutton(app.AtlasingPanel, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.DelimiterDropDownLabel = uilabel(app.AtlasingPanel);
            app.DelimiterDropDownLabel.Enable = 'off';
            app.DelimiterDropDownLabel.Text = 'Delimiter';
            app.DelimiterDropDown = uidropdown(app.AtlasingPanel);
            app.DelimiterDropDown.Items = {'semicolon', 'comma', 'space', 'tabulator'};
            app.DelimiterDropDown.ItemsData = [1 2 3 4];
            app.DelimiterDropDown.Editable = 'on';
            app.DelimiterDropDown.ValueChangedFcn = createCallbackFcn(app, @DelimiterDropDownValueChanged, true);
            app.DelimiterDropDown.Enable = 'off';
            app.DelimiterDropDown.Value = 1;
            app.ReadtableheaderButton = uibutton(app.AtlasingPanel, 'push');
            app.ReadtableheaderButton.ButtonPushedFcn = createCallbackFcn(app, @ReadtableheaderButtonPushed, true);
            app.ReadtableheaderButton.Enable = 'off';
            app.ReadtableheaderButton.Text = 'Read table header';
            app.ROIIDDropDownLabel = uilabel(app.AtlasingPanel);
            app.ROIIDDropDownLabel.Enable = 'off';
            app.ROIIDDropDownLabel.Text = 'ROI ID';
            app.ROIIDDropDown = uidropdown(app.AtlasingPanel);
            app.ROIIDDropDown.Enable = 'off';
            app.ROInameDropDownLabel = uilabel(app.AtlasingPanel);
            app.ROInameDropDownLabel.Enable = 'off';
            app.ROInameDropDownLabel.Text = 'ROI name';
            app.ROInameDropDown = uidropdown(app.AtlasingPanel);
            app.ROInameDropDown.Enable = 'off';
            app.ThresholdingPanel = uipanel(app.AnalysisTab);
            app.ThresholdingPanel.Title = 'Thresholding';
            app.TwothresholdmodeCheckBox = uicheckbox(app.ThresholdingPanel);
            app.TwothresholdmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TwothresholdmodeCheckBoxValueChanged, true);
            app.TwothresholdmodeCheckBox.Text = 'Two-threshold mode';
            app.Threshold1EditFieldLabel = uilabel(app.ThresholdingPanel);
            app.Threshold1EditFieldLabel.Text = 'Threshold 1';
            app.Threshold1EditField = uieditfield(app.ThresholdingPanel, 'numeric');
            app.Threshold1EditField.ValueChangedFcn = createCallbackFcn(app, @Threshold1EditFieldValueChanged, true);
            app.Threshold2EditFieldLabel = uilabel(app.ThresholdingPanel);
            app.Threshold2EditFieldLabel.Text = 'Threshold 2';
            app.Threshold2EditField = uieditfield(app.ThresholdingPanel, 'numeric');
            app.ThresholdtypeDropDown = uidropdown(app.ThresholdingPanel);
            app.ThresholdtypeDropDown.Items = {'<', '<=', '>', '>=', '=='};
            app.ThresholdtypeDropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.ThresholdtypeDropDown.Value = '1';
            app.MinimumLabel = uilabel(app.ThresholdingPanel);
            app.MinimumLabel.Tooltip = {''};
            app.MinimumLabel.Text = 'Minimum % of voxels in ROI  ';
            app.MinimumVoxPercEditField = uieditfield(app.ThresholdingPanel, 'numeric');
            app.MinimumVoxPercEditField.Value = 1;
            app.OperatorLabel = uilabel(app.ThresholdingPanel);
            app.OperatorLabel.Text = 'Operator';
            app.OutputresultsPanel = uipanel(app.AnalysisTab);
            app.OutputresultsPanel.Title = 'Output results';
            app.OutputPathEditField = uieditfield(app.OutputresultsPanel, 'text');
            app.SelectdirButton = uibutton(app.OutputresultsPanel, 'push');
            app.SelectdirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirButtonPushed, true);
            app.SelectdirButton.Text = 'Select dir';
            app.PatternDropDownLabel = uilabel(app.AnalysisTab);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.AnalysisTab);
            app.AnalysesListBoxLabel = uilabel(app.AnalysisTab);
            app.AnalysesListBoxLabel.Text = 'Analyses';
            app.AnalysesListBox = uilistbox(app.AnalysisTab);
            app.AnalysesListBox.Multiselect = 'on';
            app.AnalysesListBox.ValueChangedFcn = createCallbackFcn(app, @AnalysesListBoxValueChanged, true);
            app.AnalysesListBox.Value = {'Item 1'};
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.Tree = uitree(app.PlottingTab, 'checkbox');
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.Node2, app.Node3, app.Node4, app.Node];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.SelectmeasureDropDownLabel = uilabel(app.PlottingTab);
            app.SelectmeasureDropDownLabel.Text = 'Select measure';
            app.SelectmeasureDropDown = uidropdown(app.PlottingTab);
            app.SelectmeasureDropDown.Items = {'K [%]', 'Mean', 'Min', 'Max'};
            app.SelectmeasureDropDown.ItemsData = [1 2 3 4];
            app.SelectmeasureDropDown.Value = 1;
            app.SpiderplotconfigurationPanel = uipanel(app.PlottingTab);
            app.SpiderplotconfigurationPanel.Title = 'Spider plot configuration';
            app.AxesdirectionDropDownLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.AxesdirectionDropDownLabel.Text = 'Axes direction';
            app.AxesdirectionDropDown = uidropdown(app.SpiderplotconfigurationPanel);
            app.AxesdirectionDropDown.Items = {'normal', 'reverse'};
            app.AxesdirectionDropDown.Value = 'normal';
            app.LabelfontsizeSpinnerLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.LabelfontsizeSpinnerLabel.Text = 'Label font size';
            app.LabelfontsizeSpinner = uispinner(app.SpiderplotconfigurationPanel);
            app.LabelfontsizeSpinner.Value = 8;
            app.AxesfontsizeSpinnerLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.AxesfontsizeSpinnerLabel.Text = 'Axes font size';
            app.AxesfontsizeSpinner = uispinner(app.SpiderplotconfigurationPanel);
            app.AxesfontsizeSpinner.Value = 12;
            app.LabelfontsizeSpinner_2Label = uilabel(app.SpiderplotconfigurationPanel);
            app.LabelfontsizeSpinner_2Label.Text = 'Legend font size';
            app.LegendfontsizeSpinner = uispinner(app.SpiderplotconfigurationPanel);
            app.LegendfontsizeSpinner.Value = 8;
            app.LinecolorEditFieldLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.LinecolorEditFieldLabel.Text = 'Line color';
            app.LinecolorEditField = uieditfield(app.SpiderplotconfigurationPanel, 'numeric');
            app.LinecolorEditField.Value = 2;
            app.LinewidthSpinnerLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.LinewidthSpinnerLabel.Text = 'Line width';
            app.LinewidthSpinner = uispinner(app.SpiderplotconfigurationPanel);
            app.LinewidthSpinner.Value = 2;
            app.MinimumEditFieldLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.SpiderplotconfigurationPanel, 'numeric');
            app.MinimumEditField.Value = Inf;
            app.MaximumEditFieldLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.SpiderplotconfigurationPanel, 'numeric');
            app.MaximumEditField.Value = Inf;
            app.MarkerDropDownLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.SpiderplotconfigurationPanel);
            app.MarkerDropDown.Items = {'none', 'o', '*', 'square', 'diamond'};
            app.MarkerDropDown.ItemsData = [1 2 3 4 5];
            app.MarkerDropDown.Value = 1;
            app.MarkersizeSpinnerLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.MarkersizeSpinnerLabel.Text = 'Marker size';
            app.MarkersizeSpinner = uispinner(app.SpiderplotconfigurationPanel);
            app.MarkersizeSpinner.Value = 4;
            app.AxesintervalSpinnerLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.AxesintervalSpinnerLabel.Text = 'Axes interval';
            app.AxesintervalSpinner = uispinner(app.SpiderplotconfigurationPanel);
            app.AxesintervalSpinner.Value = 3;
            app.AxesprecisionSpinnerLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.AxesprecisionSpinnerLabel.Text = 'Axes precision';
            app.AxesprecisionSpinner = uispinner(app.SpiderplotconfigurationPanel);
            app.AxesprecisionSpinner.Value = 1;
            app.MinorgridCheckBox = uicheckbox(app.SpiderplotconfigurationPanel);
            app.MinorgridCheckBox.Text = 'Minor grid';
            app.MinorgridlinesSpinnerLabel = uilabel(app.SpiderplotconfigurationPanel);
            app.MinorgridlinesSpinnerLabel.Text = 'Minor gridlines';
            app.MinorgridlinesSpinner = uispinner(app.SpiderplotconfigurationPanel);
            app.MinorgridlinesSpinner.Value = 3;
            app.PlotPanel = uipanel(app.GridLayout);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            colormap(app.UIAxes, 'parula')
            % Show the figure after all components are created
            app.ROIMapperUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSpiderPlot(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ROIMapperUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ROIMapperUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIMapperUIFigure)
        end
    end
end