classdef nk_OOCVDataIO_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        cmdCreateNew             matlab.ui.control.Button
        chkCalibrationDataAvail  matlab.ui.control.CheckBox
        chkLabelKnown            matlab.ui.control.CheckBox
        cmdSave                  matlab.ui.control.Button
        cmdLoad                  matlab.ui.control.Button
        cmdDelete                matlab.ui.control.Button
        txtNewData               matlab.ui.control.EditField
        lstData                  matlab.ui.control.ListBox
        pushbutton2              matlab.ui.control.Button
        cmdSelect                matlab.ui.control.Button
    end

    
    methods (Access = private)
        function print_dataitems(app, handles, value)
            if ~exist('value','var') || isempty(value), value = numel(handles.Data.Items); end
            if ~isempty(handles.Data.Items)
                for i=1:numel(handles.Data.Items)
                    if isfield(handles.Data.Items{i},'labels_known')
                        if handles.Data.Items{i}.labels_known
                            lbknown_str = '; labels known: yes';
                        else
                            lbknown_str = '; labels known: no';
                        end
                    else
                        lbknown_str = '';
                    end
                    if ~isfield(handles.Data.Items{i},'n_subjects_all')
                        n_subjects_all=0;
                    else
                        n_subjects_all= handles.Data.Items{i}.n_subjects_all;
                    end
                    datastr{i} = sprintf('[%3i] %s (%g cases); date: %s%s',i,handles.Data.Items{i}.desc,n_subjects_all,handles.Data.Items{i}.date, lbknown_str);
                end
                handles.lstData.String = datastr;
                handles.lstData.Value=value;
            else
                set(handles.lstData, 'String', 'NO DATA AVALABLE');
                handles.lstData.Value=1;
            end
             toggle_controls(app, handles)
        end
        
        function toggle_controls(app, handles)
            
            if ~isfield(handles,'Data') || ~isfield(handles.Data,'Items') || isempty(handles.Data.Items);
                tglstr = 'off';
            else
                tglstr = 'on';
            end
            
            set(handles.cmdSelect,'Enable',tglstr);
            set(handles.cmdSave,'Enable',tglstr);
            set(handles.cmdDelete,'Enable',tglstr);
            set(handles.lstData,'Enable',tglstr);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function nk_OOCVDataIO_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to nk_OOCVDataIO (see VARARGIN)
            
            % Choose default command line output for nk_OOCVDataIO
            handles.output = [];
            
            % Update handles structure
            guidata(hObject, handles);
            
            % Insert custom Title and Text if specified by the user
            % Hint: when choosing keywords, be sure they are not easily confused
            % with existing figure properties.  See the output of set(figure) for
            % a list of figure properties.
            if(nargin > 3)
                for index = 1:2:(nargin-3),
                    if nargin-3==index, break, end
                    switch lower(varargin{index})
                         case 'title'
                            set(hObject, 'Name', varargin{index+1});
                         case 'list'
                             if ~isempty(varargin{index+1})
                                for i=1:numel(varargin{index+1})
                                    handles.Data.Items{i} = varargin{index+1}{i};
                                end
                             else
                                handles.Data.Items=[];
                             end
                             guidata(handles.figure1,handles);
                             print_dataitems(app, handles)
                          case 'mode'
                             switch varargin{index+1}
                                 case 'select'
                                     bt_str = 'Select dataset'; vis_str = 'off';
                                     set(handles.lstData,'Position',[0.034 0.05 0.93 0.82]);
                                     handles.mode = 0;
                                 case 'modify'
                                     bt_str = 'Add/Modify Data in Dataset'; vis_str = 'on';
                                     set(handles.lstData,'Position',[0.034 0.17 0.93 0.70]);
                                     handles.mode = 1;
                             end
                             set(handles.cmdSelect,'String',bt_str);
                             set(handles.cmdDelete,'Visible',vis_str);
                             set(handles.txtNewData,'Visible',vis_str);
                             set(handles.cmdCreateNew,'Visible',vis_str);
                             set(handles.chkLabelKnown,'Visible',vis_str);
                             set(handles.chkCalibrationDataAvail,'Visible',vis_str);
                             set(handles.cmdSave,'Visible',vis_str);
                        case 'multiselection'
                            switch varargin{index+1}
                                case 0
                                    handles.lstData.MultiSelect='off';
                                case 1
                                    handles.lstData.MultiSelect='on';
                            end
                    end
                end
            end
            
            % Determine the position of the dialog - centered on the callback figure
            % if available, else, centered on the screen
            FigPos=get(0,'DefaultFigurePosition');
            OldUnits = get(hObject, 'Units');
            set(hObject, 'Units', 'pixels');
            OldPos = get(hObject,'Position');
            FigWidth = OldPos(3);
            FigHeight = OldPos(4);
            if isempty(gcbf)
                ScreenUnits=get(0,'Units');
                set(0,'Units','pixels');
                ScreenSize=get(0,'ScreenSize');
                set(0,'Units',ScreenUnits);
            
                FigPos(1)=1/2*(ScreenSize(3)-FigWidth);
                FigPos(2)=2/3*(ScreenSize(4)-FigHeight);
            else
                GCBFOldUnits = get(gcbf,'Units');
                set(gcbf,'Units','pixels');
                GCBFPos = get(gcbf,'Position');
                set(gcbf,'Units',GCBFOldUnits);
                FigPos(1:2) = [(GCBFPos(1) + GCBFPos(3) / 2) - FigWidth / 2, ...
                               (GCBFPos(2) + GCBFPos(4) / 2) - FigHeight / 2];
            end
            FigPos(3:4)=[FigWidth FigHeight];
            set(hObject, 'Position', FigPos);
            set(hObject, 'Units', OldUnits);
            
            % Make the GUI modal
            set(handles.figure1,'WindowStyle','modal')
            
            % UIWAIT makes nk_OOCVDataIO wait for user response (see UIRESUME)
            uiwait(handles.figure1);
        end

        % Button pushed function: cmdCreateNew
        function cmdCreateNew_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdCreateNew (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if isfield(handles.Data,'Items'), l = numel(handles.Data.Items)+1; else l=1; end
            handles.Data.Items{l} = struct('desc', get(handles.txtNewData,'String'), ...
                                            'date',datestr(now), ...
                                            'n_subjects_all',0, ...
                                            'labels_known', get(handles.chkLabelKnown,'Value'), ...
                                            'os', sprintf('%s', system_dependent('getos')));
            if isfield(handles.Data,'NewItemIndex'), lx = numel(handles.Data.NewItemIndex)+1; else lx = 1; end
            handles.Data.NewItemIndex(lx) = l;
            print_dataitems(app, handles)
            guidata(handles.figure1,handles);
        end

        % Button pushed function: cmdDelete
        function cmdDelete_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdDelete (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            selItem = get(handles.lstData,'Value');
            lstItems = get(handles.lstData,'String');
            delfl = questdlg(sprintf('Are you sure you want to delete dataset:\n%s',lstItems{selItem}));
            if strcmp(delfl,'Yes')
            %     handles.Data.Items(selItem) = [];
            %     if isfield(handles.Data,'NewItems')
            %         ind = find(handles.Data.NewItems, selItem);
            %         handles.Data.NewItems(ind)=[];
            %     end
            
                handles.output.delete = selItem;
                guidata(handles.figure1,handles);
                uiresume(handles.figure1)
            
            %     % Update handles structure
            %     guidata(hObject, handles);
            %
            %     print_dataitems(handles,selItem-1);
            end
        end

        % Button pushed function: cmdLoad
        function cmdLoad_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdLoad (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            n = numel(handles.Data.Items);
            
            [filename,pathname] = uigetfile('*.mat','Load Independent Test Data','MultiSelect','on');
            if iscell(filename)
                nF = numel(filename);
            else
                nF=1;
            end
            if filename
                for i=1:nF
                    if iscell(filename)
                        pth = fullfile(pathname,filename{i});
                        load(pth)
                    else
                        pth = fullfile(pathname,filename);
                        load(pth)
                    end
                    handles.Data.Items{n+i} = OOCV;
                end
            end
            handles.Data.load=true;
            guidata(handles.figure1,handles);
            print_dataitems(app, handles)
        end

        % Button pushed function: cmdSave
        function cmdSave_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdSave (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            selItem = get(handles.lstData,'Value');
            %lstItems = get(handles.lstData,'String');
            OOCV = handles.Data.Items{selItem};
            filename = 'NM_OOCV_';
            uisave('OOCV',filename);
        end

        % Button pushed function: cmdSelect
        function cmdSelect_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cmdSelect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.Data.SelItem = get(handles.lstData,'Value');
            handles.output = handles.Data;
            handles.output.exit = true;
            % Update handles structure
            guidata(hObject, handles);
            
            % Use UIRESUME instead of delete because the OutputFcn needs
            % to get the updated handles structure.
            uiresume(handles.figure1);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if isequal(get(hObject, 'waitstatus'), 'waiting')
                % The GUI is still in UIWAIT, us UIRESUME
                uiresume(hObject);
            else
                % The GUI is no longer waiting, just close it
                delete(hObject);
            end
            handles.output = handles.Data;
            handles.output.exit = true;
            % Update handles structure
            %guidata(hObject, handles);
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Check for "enter" or "escape"
            if isequal(get(hObject,'CurrentKey'),'escape')
                % User said no by hitting escape
                handles.output = 'No';
            
                % Update handles structure
                guidata(hObject, handles);
            
                uiresume(handles.figure1);
            end
            
            if isequal(get(hObject,'CurrentKey'),'return')
                uiresume(handles.figure1);
            end
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.output = handles.Data;
            handles.output.exit = true;
            % Update handles structure
            guidata(hObject, handles);
            
            % Use UIRESUME instead of delete because the OutputFcn needs
            % to get the updated handles structure.
            uiresume(handles.figure1);
        end

        % Callback function
        function uiInspectPredictions_ClickedCallback(app, event)
            
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uiInspectPredictions.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function
        function uiImportData_ClickedCallback(app, event)
            % Create a new UI figure
            uifigure('WindowStyle', app.figure1.WindowStyle);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'nk_OOCVDataIO';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.cmdSelect = uibutton(app.figure1, 'push');
            app.cmdSelect.ButtonPushedFcn = createCallbackFcn(app, @cmdSelect_Callback, true);
            app.cmdSelect.Tag = 'cmdSelect';
            app.cmdSelect.Text = 'Select Dataset';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Tooltip = 'Exit Independent Test Data Manager';
            app.pushbutton2.Text = 'Exit';
            app.lstData = uilistbox(app.figure1);
            app.lstData.Items = {'LMU dataset', 'UBS dataset', 'UKK dataset'};
            app.lstData.Tag = 'lstData';
            app.lstData.Tooltip = 'Select analyses to operate on';
            app.lstData.Value = 'LMU dataset';
            app.txtNewData = uieditfield(app.figure1, 'text');
            app.txtNewData.Tag = 'txtNewData';
            app.txtNewData.Tooltip = 'Enter new dataset description here';
            app.cmdDelete = uibutton(app.figure1, 'push');
            app.cmdDelete.ButtonPushedFcn = createCallbackFcn(app, @cmdDelete_Callback, true);
            app.cmdDelete.Tag = 'cmdDelete';
            app.cmdDelete.Tooltip = 'Delete selected independent test data';
            app.cmdDelete.Text = 'Delete';
            app.cmdLoad = uibutton(app.figure1, 'push');
            app.cmdLoad.ButtonPushedFcn = createCallbackFcn(app, @cmdLoad_Callback, true);
            app.cmdLoad.Tag = 'cmdLoad';
            app.cmdLoad.Tooltip = 'Load independent test data';
            app.cmdLoad.Text = 'Load';
            app.cmdSave = uibutton(app.figure1, 'push');
            app.cmdSave.ButtonPushedFcn = createCallbackFcn(app, @cmdSave_Callback, true);
            app.cmdSave.Tag = 'cmdSave';
            app.cmdSave.Tooltip = 'Save selected independent test data';
            app.cmdSave.Text = 'Save';
            app.chkLabelKnown = uicheckbox(app.figure1);
            app.chkLabelKnown.Tag = 'chkLabelKnown';
            app.chkLabelKnown.Text = 'Target labels known';
            app.chkCalibrationDataAvail = uicheckbox(app.figure1);
            app.chkCalibrationDataAvail.Tag = 'chkCalibrationDataAvail';
            app.chkCalibrationDataAvail.Enable = 'off';
            app.chkCalibrationDataAvail.Text = 'Calibration data available';
            app.cmdCreateNew = uibutton(app.figure1, 'push');
            app.cmdCreateNew.ButtonPushedFcn = createCallbackFcn(app, @cmdCreateNew_Callback, true);
            app.cmdCreateNew.Tag = 'cmdCreateNew';
            app.cmdCreateNew.Text = 'Create New ...';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nk_OOCVDataIO_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)nk_OOCVDataIO_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end