classdef simSample_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExportfigureButton          matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        priorTab                    matlab.ui.container.Tab
        SimulateButton              matlab.ui.control.Button
        NcasesEditField             matlab.ui.control.EditField
        NcasesEditFieldLabel        matlab.ui.control.Label
        MLmodelEditField            matlab.ui.control.EditField
        MLmodelEditFieldLabel       matlab.ui.control.Label
        NAperfeatureEditField       matlab.ui.control.NumericEditField
        NAperfeatureEditFieldLabel  matlab.ui.control.Label
        NApercaseEditField          matlab.ui.control.NumericEditField
        NApercaseEditFieldLabel     matlab.ui.control.Label
        BatchPercEditField          matlab.ui.control.NumericEditField
        BatchEditFieldLabel         matlab.ui.control.Label
        NbatchesEditField           matlab.ui.control.NumericEditField
        NbatchesEditFieldLabel      matlab.ui.control.Label
        minAUCEditField             matlab.ui.control.NumericEditField
        minAUCLabel                 matlab.ui.control.Label
        maxAUCEditField             matlab.ui.control.NumericEditField
        maxAUCEditFieldLabel        matlab.ui.control.Label
        RelativedistributionlabelsEditField  matlab.ui.control.NumericEditField
        RelativedistributionlabelsEditFieldLabel  matlab.ui.control.Label
        predictivemarkersEditField  matlab.ui.control.NumericEditField
        predictivemarkersEditFieldLabel  matlab.ui.control.Label
        NfeaturesEditField          matlab.ui.control.NumericEditField
        NfeaturesEditFieldLabel     matlab.ui.control.Label
        postTab                     matlab.ui.container.Tab
        ExcludeEditField            matlab.ui.control.EditField
        ExcludeCheckBox             matlab.ui.control.CheckBox
        TextArea                    matlab.ui.control.TextArea
        LabelvariableEditField      matlab.ui.control.EditField
        LabelEditFieldLabel         matlab.ui.control.Label
        NcasesEditField_post        matlab.ui.control.EditField
        NcasesEditField_2Label      matlab.ui.control.Label
        SimulateButton_post         matlab.ui.control.Button
        FileEditField               matlab.ui.control.EditField
        FileEditFieldLabel          matlab.ui.control.Label
        SelectButton                matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        loadedNMData
        NM_Y
        NM_label
    end    


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin > 1  %data is provided
                app.NM_Y = varargin{1,1}{1,1};
                app.NM_label = varargin{1,2};
                app.loadedNMData = 1;
                app.TextArea.Value = 'data from NM structure';
                app.TextArea.Visible = 1;
                app.FileEditField.Editable = 0;
                app.FileEditField.Enable = 0;
                app.ExcludeCheckBox.Enable = 0;
                app.LabelvariableEditField.Editable = 0; 
                app.LabelvariableEditField.Enable = 0; 
                %app.priorTab.HandleVisibility = 0;
            else 
                app.loadedNMData = 1;
            end
        end

        % Value changed function: NfeaturesEditField
        function NfeaturesEditFieldValueChanged(app, event)
          
        end

        % Value changed function: predictivemarkersEditField
        function predictivemarkersEditFieldValueChanged(app, event)
      
        end

        % Value changed function: RelativedistributionlabelsEditField
        function RelativedistributionlabelsEditFieldValueChanged(app, event)
          
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            IN.Nfeats = app.NfeaturesEditField.Value;
            IN.Nmarkers = app.predictivemarkersEditField.Value;
            IN.Ncases = str2num(app.NcasesEditField.Value);
            IN.eventprob = app.RelativedistributionlabelsEditField.Value;
            IN.AUCmax = app.maxAUCEditField.Value;
            IN.AUCmin = app.minAUCEditField.Value;
            IN.Nbatches = app.NbatchesEditField.Value;
            IN.BatchPerc = app.BatchPercEditField.Value;
            IN.NcasesMiss = app.RelativedistributionlabelsEditField.Value;
            IN.NfeatsMiss = app.RelativedistributionlabelsEditField.Value;
            IN.algorithm = str2num(app.MLmodelEditField.Value);
            app.UIAxes.Visible = 'on';
            simSamp = nk_SimulateML2(IN, app.UIAxes);
            %app.ResultsTextArea = evalc(simSamp);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'posthoc'
                    app.NfeaturesEditField.Visible = 'off';
                    app.NfeaturesEditFieldLabel.Visible = 'off';
                case 'prior'
                    app.NfeaturesEditField.Visible = 'off';
                    app.NfeaturesEditFieldLabel.Visible = 'off';
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');
            app.FileEditField.Value = fullfile(path, file);
            
            app.data = readtable(fullfile(path,file));
            app.TextArea.Visible = 'on';
            %infotext = {};
            infotext = [sprintf('Dataset: \nN features: %d \nN observations: %d\n\n', ...
                size(app.data,2), size(app.data,1))];
            if isempty(app.LabelvariableEditField.Value)
                varinfo = [sprintf('Variable names:\n')];
                for i = 1:numel(app.data.Properties.VariableNames)
                    varinfo = [varinfo, sprintf('%s\n', ...
                        app.data.Properties.VariableNames{1,i})];
                end
                infotext = [infotext, varinfo];

                %else
             %   n_groups = 
             %   infotext = {sprintf()}
            end

            
            app.TextArea.Value = infotext;
            app.LabelvariableEditField.Editable = 'on';
            app.ExcludeCheckBox.Enable = 'on';
       
        end

        % Button pushed function: SimulateButton_post
        function SimulateButton_postPushed(app, event)
            % prepare dataset
            % check if variables (e.g., ID) should be excluded

            if app.loadedNMData
                feat = app.NM_Y;
                labels = app.NM_label;
            else
                fulldata = app.data;
                if ~isempty(app.ExcludeEditField.Value)
                    fulldata = removevars(fulldata,app.ExcludeEditField.Value);
                end
                feat = removevars(fulldata,app.LabelvariableEditField.Value);
                labels = eval(sprintf('data.%s', app.LabelvariableEditField.Value));
            end
            IN.Data = feat; 
            IN.DataLabel= labels;
            IN.Nfeats = size(IN.Data,2);
            IN.Ncases = str2num(app.NcasesEditField_post.Value);
            IN.Nmarkers = app.predictivemarkersEditField.Value; %unused
            IN.eventprob = app.RelativedistributionlabelsEditField.Value; %unused
            IN.AUCmax = app.maxAUCEditField.Value;
            IN.AUCmin = app.minAUCEditField.Value;
            IN.Nbatches = app.NbatchesEditField.Value;
            IN.BatchPerc = app.BatchPercEditField.Value;
            IN.NcasesMiss = app.RelativedistributionlabelsEditField.Value;
            IN.NfeatsMiss = app.RelativedistributionlabelsEditField.Value;
            IN.algorithm = str2num(app.MLmodelEditField.Value);
            IN.verbose = 0;
            app.UIAxes.Visible = 'on';
            simSamp = nk_SimulateML2(IN, app.UIAxes);
            app.ExportfigureButton.Visible = 'on';
            
        end

        % Value changed function: ExcludeCheckBox
        function ExcludeCheckBoxValueChanged(app, event)
            value = app.ExcludeCheckBox.Value;
            if value
                app.ExcludeEditField.Visible = 'on';
            else 
                app.ExcludeEditField.Visible = 'off';
            end
        end

        % Value changing function: LabelvariableEditField
        function LabelvariableEditFieldValueChanging(app, event)
            
        end

        % Value changed function: LabelvariableEditField
        function LabelvariableEditFieldValueChanged(app, event)
            value = app.LabelvariableEditField.Value;
            
        end

        % Button pushed function: ExportfigureButton
        function ExportfigureButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportapp(app.UIAxes,fullfile(filepath,filename));
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sample size simulation')
            xlabel(app.UIAxes, 'N Cases')
            ylabel(app.UIAxes, 'Balanced Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.priorTab = uitab(app.TabGroup);
            app.priorTab.Title = 'prior';
            app.NfeaturesEditFieldLabel = uilabel(app.priorTab);
            app.NfeaturesEditFieldLabel.Text = 'N features';
            app.NfeaturesEditField = uieditfield(app.priorTab, 'numeric');
            app.NfeaturesEditField.ValueChangedFcn = createCallbackFcn(app, @NfeaturesEditFieldValueChanged, true);
            app.NfeaturesEditField.Value = 100;
            app.predictivemarkersEditFieldLabel = uilabel(app.priorTab);
            app.predictivemarkersEditFieldLabel.Text = '% predictive markers';
            app.predictivemarkersEditField = uieditfield(app.priorTab, 'numeric');
            app.predictivemarkersEditField.ValueChangedFcn = createCallbackFcn(app, @predictivemarkersEditFieldValueChanged, true);
            app.predictivemarkersEditField.Value = 0.2;
            app.RelativedistributionlabelsEditFieldLabel = uilabel(app.priorTab);
            app.RelativedistributionlabelsEditFieldLabel.Text = 'Relative distribution labels';
            app.RelativedistributionlabelsEditField = uieditfield(app.priorTab, 'numeric');
            app.RelativedistributionlabelsEditField.ValueChangedFcn = createCallbackFcn(app, @RelativedistributionlabelsEditFieldValueChanged, true);
            app.RelativedistributionlabelsEditField.Value = 0.5;
            app.maxAUCEditFieldLabel = uilabel(app.priorTab);
            app.maxAUCEditFieldLabel.Text = 'max AUC';
            app.maxAUCEditField = uieditfield(app.priorTab, 'numeric');
            app.maxAUCEditField.Value = 0.7;
            app.minAUCLabel = uilabel(app.priorTab);
            app.minAUCLabel.Text = 'min AUC';
            app.minAUCEditField = uieditfield(app.priorTab, 'numeric');
            app.minAUCEditField.Value = 0.5;
            app.NbatchesEditFieldLabel = uilabel(app.priorTab);
            app.NbatchesEditFieldLabel.Text = 'N batches';
            app.NbatchesEditField = uieditfield(app.priorTab, 'numeric');
            app.BatchEditFieldLabel = uilabel(app.priorTab);
            app.BatchEditFieldLabel.Text = 'Batch %';
            app.BatchPercEditField = uieditfield(app.priorTab, 'numeric');
            app.BatchPercEditField.Value = 0.1;
            app.NApercaseEditFieldLabel = uilabel(app.priorTab);
            app.NApercaseEditFieldLabel.Text = '% NA per case';
            app.NApercaseEditField = uieditfield(app.priorTab, 'numeric');
            app.NAperfeatureEditFieldLabel = uilabel(app.priorTab);
            app.NAperfeatureEditFieldLabel.Text = '% NA per feature';
            app.NAperfeatureEditField = uieditfield(app.priorTab, 'numeric');
            app.MLmodelEditFieldLabel = uilabel(app.priorTab);
            app.MLmodelEditFieldLabel.Text = 'ML model';
            app.MLmodelEditField = uieditfield(app.priorTab, 'text');
            app.MLmodelEditField.Value = 'LIBKERNSVM';
            app.NcasesEditFieldLabel = uilabel(app.priorTab);
            app.NcasesEditFieldLabel.Text = 'N cases';
            app.NcasesEditField = uieditfield(app.priorTab, 'text');
            app.NcasesEditField.Value = '[100 200 300 400]';
            app.SimulateButton = uibutton(app.priorTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.postTab = uitab(app.TabGroup);
            app.postTab.Title = 'post';
            app.SelectButton = uibutton(app.postTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.FileEditFieldLabel = uilabel(app.postTab);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.postTab, 'text');
            app.SimulateButton_post = uibutton(app.postTab, 'push');
            app.SimulateButton_post.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_postPushed, true);
            app.SimulateButton_post.Text = 'Simulate';
            app.NcasesEditField_2Label = uilabel(app.postTab);
            app.NcasesEditField_2Label.Text = 'N cases';
            app.NcasesEditField_post = uieditfield(app.postTab, 'text');
            app.NcasesEditField_post.Value = '[100 200 300 400]';
            app.LabelEditFieldLabel = uilabel(app.postTab);
            app.LabelEditFieldLabel.Text = 'Label variable';
            app.LabelvariableEditField = uieditfield(app.postTab, 'text');
            app.LabelvariableEditField.ValueChangedFcn = createCallbackFcn(app, @LabelvariableEditFieldValueChanged, true);
            app.LabelvariableEditField.ValueChangingFcn = createCallbackFcn(app, @LabelvariableEditFieldValueChanging, true);
            app.LabelvariableEditField.Editable = 'off';
            app.TextArea = uitextarea(app.postTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.ExcludeCheckBox = uicheckbox(app.postTab);
            app.ExcludeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeCheckBoxValueChanged, true);
            app.ExcludeCheckBox.Enable = 'off';
            app.ExcludeCheckBox.Text = 'ID variable (to exclude)';
            app.ExcludeEditField = uieditfield(app.postTab, 'text');
            app.ExcludeEditField.Visible = 'off';
            app.ExportfigureButton = uibutton(app.UIFigure, 'push');
            app.ExportfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfigureButtonPushed, true);
            app.ExportfigureButton.Visible = 'off';
            app.ExportfigureButton.Text = 'Export figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simSample_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end