classdef simSample_App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExportfigureButton             matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        priorTab                       matlab.ui.container.Tab
        SimulateButton                 matlab.ui.control.Button
        NcasesEditField                matlab.ui.control.EditField
        NcasesEditFieldLabel           matlab.ui.control.Label
        MLmodelEditField               matlab.ui.control.EditField
        MLmodelEditFieldLabel          matlab.ui.control.Label
        NAperfeatureEditField          matlab.ui.control.NumericEditField
        NAperfeatureEditFieldLabel     matlab.ui.control.Label
        NApercaseEditField             matlab.ui.control.NumericEditField
        NApercaseEditFieldLabel        matlab.ui.control.Label
        BatchPercEditField             matlab.ui.control.NumericEditField
        BatchEditFieldLabel            matlab.ui.control.Label
        NbatchesEditField              matlab.ui.control.NumericEditField
        NbatchesEditFieldLabel         matlab.ui.control.Label
        minAUCEditField                matlab.ui.control.NumericEditField
        minAUCLabel                    matlab.ui.control.Label
        maxAUCEditField                matlab.ui.control.NumericEditField
        maxAUCEditFieldLabel           matlab.ui.control.Label
        RelativedistributionlabelsEditField  matlab.ui.control.NumericEditField
        RelativedistributionlabelsEditFieldLabel  matlab.ui.control.Label
        predictivemarkersEditField     matlab.ui.control.NumericEditField
        predictivemarkersEditFieldLabel  matlab.ui.control.Label
        NfeaturesEditField             matlab.ui.control.NumericEditField
        NfeaturesEditFieldLabel        matlab.ui.control.Label
        postTab                        matlab.ui.container.Tab
        OkButton                       matlab.ui.control.Button
        AnalysistosimulateEditField    matlab.ui.control.NumericEditField
        AnalysistosimulateEditFieldLabel  matlab.ui.control.Label
        AnalysesTextArea               matlab.ui.control.TextArea
        NcasesEditField_post           matlab.ui.control.EditField
        NcasesEditField_2Label         matlab.ui.control.Label
        SimulateButton_post            matlab.ui.control.Button
        post_2                         matlab.ui.container.Tab
        addCheckBox                    matlab.ui.control.CheckBox
        TextArea_5                     matlab.ui.control.TextArea
        AnalysistosimulateEditField_2  matlab.ui.control.NumericEditField
        AnalysistosimulateEditField_2Label  matlab.ui.control.Label
        NcasesEditField_add_data       matlab.ui.control.EditField
        NcasesEditField_2Label_3       matlab.ui.control.Label
        OkButton_2                     matlab.ui.control.Button
        AnalysesTextArea_2             matlab.ui.control.TextArea
        SimulateButton_add_group       matlab.ui.control.Button
        post_oldTab                    matlab.ui.container.Tab
        AnalysisNoEditField_2          matlab.ui.control.NumericEditField
        AnalysisNoEditField_2Label     matlab.ui.control.Label
        TextArea_4                     matlab.ui.control.TextArea
        ExcludeEditField_2             matlab.ui.control.EditField
        ExcludeCheckBox_2              matlab.ui.control.CheckBox
        TextArea_3                     matlab.ui.control.TextArea
        LabelvariableEditField_2       matlab.ui.control.EditField
        LabelvariableEditField_2Label  matlab.ui.control.Label
        NcasesEditField_post_2         matlab.ui.control.EditField
        NcasesEditField_2Label_2       matlab.ui.control.Label
        SimulateButton_post_2          matlab.ui.control.Button
        OriginaldataEditField_2        matlab.ui.control.EditField
        OriginaldataEditField_2Label   matlab.ui.control.Label
        SelectButton_2                 matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data % Description
        loadedNMData
        NM_Ys
        %NM_Y
        NM_analyses
        NM_label
        NM_struct
     
    end    
    
    properties (Access = public)
        NM_Y % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin > 1
                app.NM_struct = varargin(1);
                app.NM_struct = app.NM_struct{1};
                app.NM_Ys = app.NM_struct.Y;
                app.NM_analyses = app.NM_struct.analysis;
                anal_text = sprintf('Initialized analyses:\n');
                for nanal = 1:size(app.NM_analyses,2)
                    anal_desc = app.NM_analyses{1,nanal}.id;
                    add_anal_text = sprintf('%d) %s \n', nanal, anal_desc);
                    anal_text = [anal_text, add_anal_text];
                end 
                app.AnalysesTextArea.Value = anal_text;
                app.NM_label = app.NM_struct.label;
                app.loadedNMData = 1;
                app.AnalysesTextArea.Visible = 1;
            else 
                app.loadedNMData = 0;
            end
            drawnow
        end

        % Value changed function: NfeaturesEditField
        function NfeaturesEditFieldValueChanged(app, event)
          
        end

        % Value changed function: predictivemarkersEditField
        function predictivemarkersEditFieldValueChanged(app, event)
      
        end

        % Value changed function: RelativedistributionlabelsEditField
        function RelativedistributionlabelsEditFieldValueChanged(app, event)
          
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            IN.Nfeats = app.NfeaturesEditField.Value;
            IN.Nmarkers = app.predictivemarkersEditField.Value;
            IN.Ncases = str2num(app.NcasesEditField.Value);
            IN.eventprob = app.RelativedistributionlabelsEditField.Value;
            IN.AUCmax = app.maxAUCEditField.Value;
            IN.AUCmin = app.minAUCEditField.Value;
            IN.Nbatches = app.NbatchesEditField.Value;
            IN.BatchPerc = app.BatchPercEditField.Value;
            IN.NcasesMiss = app.RelativedistributionlabelsEditField.Value;
            IN.NfeatsMiss = app.RelativedistributionlabelsEditField.Value;
            IN.algorithm = str2num(app.MLmodelEditField.Value);
            app.UIAxes.Visible = 'on';
            simSamp = nk_SimulateML2(IN, app.UIAxes);
            %app.ResultsTextArea = evalc(simSamp);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.withlabelDropDown.Value;
            switch value
                case 'posthoc'
                    app.NfeaturesEditField.Visible = 'off';
                    app.NfeaturesEditFieldLabel.Visible = 'off';
                case 'prior'
                    app.NfeaturesEditField.Visible = 'off';
                    app.NfeaturesEditFieldLabel.Visible = 'off';
            end
        end

        % Callback function
        function SelectButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');
            app.DatasetNoEditField.Value = fullfile(path, file);
            
            app.data = readtable(fullfile(path,file));
            app.TextArea.Visible = 'on';
            %infotext = {};
            infotext = sprintf('Dataset: \nN features: %d \nN observations: %d\n\n', ...
                size(app.data,2), size(app.data,1));
            if isempty(app.LabelvariableEditField.Value)
                varinfo = sprintf('Variable names:\n');
                for i = 1:numel(app.data.Properties.VariableNames)
                    varinfo = [varinfo, sprintf('%s\n', ...
                        app.data.Properties.VariableNames{1,i})];
                end
                infotext = [infotext, varinfo];

                %else
             %   n_groups = 
             %   infotext = {sprintf()}
            end

            
            app.TextArea.Value = infotext;
            app.LabelvariableEditField.Editable = 'on';
            app.ExcludeCheckBox.Enable = 'on';
       
        end

        % Button pushed function: SimulateButton_post
        function SimulateButton_postPushed(app, event)
            % prepare dataset
            % check if variables (e.g., ID) should be excluded

            if app.loadedNMData
                M = app.NM_struct.analysis{1,app.AnalysistosimulateEditField.Value}.params.TrainParam.FUSION.M;
                app.NM_Y = app.NM_Ys{1,M};
                feat = app.NM_Y;
                labels = app.NM_label;
            else
                fulldata = app.data;
                if ~isempty(app.ExcludeEditField.Value)
                    fulldata = removevars(fulldata,app.ExcludeEditField.Value);
                end
                feat = removevars(fulldata,app.LabelvariableEditField.Value);
                labels = eval(sprintf('data.%s', app.LabelvariableEditField.Value));
            end
            IN.Data = feat; 
            IN.DataLabel= labels;
            IN.Nfeats = size(IN.Data,2);
            IN.Ncases = str2num(app.NcasesEditField_post.Value);
            IN.Nmarkers = app.predictivemarkersEditField.Value; %unused
            IN.eventprob = app.RelativedistributionlabelsEditField.Value; %unused
            IN.AUCmax = app.maxAUCEditField.Value;
            IN.AUCmin = app.minAUCEditField.Value;
            IN.Nbatches = app.NbatchesEditField.Value;
            IN.BatchPerc = app.BatchPercEditField.Value;
            IN.NcasesMiss = app.RelativedistributionlabelsEditField.Value;
            IN.NfeatsMiss = app.RelativedistributionlabelsEditField.Value;
            IN.add2orig = 0;
            %IN.algorithm = 
            IN.NRanalysis = app.AnalysistosimulateEditField.Value;
            IN.verbose = 0;
            app.UIAxes.Visible = 'on';
            simSamp = nk_SimulateML2(IN, app.UIAxes);
            app.ExportfigureButton.Visible = 'on';
            
        end

        % Callback function
        function ExcludeCheckBoxValueChanged(app, event)
            value = app.ExcludeCheckBox.Value;
            if value
                app.ExcludeEditField.Visible = 'on';
            else 
                app.ExcludeEditField.Visible = 'off';
            end
        end

        % Button pushed function: ExportfigureButton
        function ExportfigureButtonPushed(app, event)
            filter = {'*.jpg';'*.png';'*.tif';'*.pdf'};
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportapp(app.UIAxes,fullfile(filepath,filename));
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.AnalysesTextArea.Value = sprintf('Analysis %d, %s selected.\n', ...
                app.AnalysistosimulateEditField.Value, ...
                app.NM_analyses{1,app.AnalysistosimulateEditField.Value}.id);
            if isempty(app.NcasesEditField_post.Value)
                app.AnalysesTextArea.Value = [app.AnalysesTextArea.Value, ...
                    sprintf('\nYou still have to define sample sizes to simulate!')];
            else 
                app.AnalysesTextArea.Value = [app.AnalysesTextArea.Value, ...
                    sprintf('\nSample sizes to simulate: %s', app.NcasesEditField_post.Value)];
            end
            M = evalin('base',sprintf('NM.analysis{1,%d}.params.TrainParam.FUSION.M',app.AnalysistosimulateEditField.Value));
            app.NM_Y = app.NM_Ys{1,M};
        end

        % Button pushed function: SimulateButton_add_group
        function SimulateButton_add_groupPushed(app, event)
             if app.loadedNMData
                M = app.NM_struct.analysis{1,app.AnalysistosimulateEditField.Value}.params.TrainParam.FUSION.M;
                app.NM_Y = app.NM_Ys{1,M};
                feat = app.NM_Y;
                labels = app.NM_label;
            else
                fulldata = app.data;
                if ~isempty(app.ExcludeEditField.Value)
                    fulldata = removevars(fulldata,app.ExcludeEditField.Value);
                end
                feat = removevars(fulldata,app.LabelvariableEditField.Value);
                labels = eval(sprintf('data.%s', app.LabelvariableEditField.Value));
            end
            IN.Data = feat; 
            IN.DataLabel= labels;
            IN.Nfeats = size(IN.Data,2);
            IN.Ncases = str2num(app.NcasesEditField_add_data.Value);
            IN.Nmarkers = app.predictivemarkersEditField.Value; %unused
            IN.eventprob = app.RelativedistributionlabelsEditField.Value; %unused
            IN.AUCmax = app.maxAUCEditField.Value;
            IN.AUCmin = app.minAUCEditField.Value;
            IN.Nbatches = app.NbatchesEditField.Value;
            IN.BatchPerc = app.BatchPercEditField.Value;
            IN.NcasesMiss = app.RelativedistributionlabelsEditField.Value;
            IN.NfeatsMiss = app.RelativedistributionlabelsEditField.Value;
            %IN.algorithm = 
            IN.NRanalysis = app.AnalysistosimulateEditField.Value;
            IN.verbose = 0;
            IN.add2orig = app.addCheckBox.Value;
            app.UIAxes.Visible = 'on';
            simSamp = nk_SimulateML2(IN, app.UIAxes);
            app.ExportfigureButton.Visible = 'on';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sample size simulation')
            xlabel(app.UIAxes, 'N Cases')
            ylabel(app.UIAxes, 'Balanced Accuracy')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            colormap(app.UIAxes, 'parula')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.priorTab = uitab(app.TabGroup);
            app.priorTab.Title = 'prior';
            app.NfeaturesEditFieldLabel = uilabel(app.priorTab);
            app.NfeaturesEditFieldLabel.Text = 'N features';
            app.NfeaturesEditField = uieditfield(app.priorTab, 'numeric');
            app.NfeaturesEditField.ValueChangedFcn = createCallbackFcn(app, @NfeaturesEditFieldValueChanged, true);
            app.NfeaturesEditField.Value = 100;
            app.predictivemarkersEditFieldLabel = uilabel(app.priorTab);
            app.predictivemarkersEditFieldLabel.Text = '% predictive markers';
            app.predictivemarkersEditField = uieditfield(app.priorTab, 'numeric');
            app.predictivemarkersEditField.ValueChangedFcn = createCallbackFcn(app, @predictivemarkersEditFieldValueChanged, true);
            app.predictivemarkersEditField.Value = 0.2;
            app.RelativedistributionlabelsEditFieldLabel = uilabel(app.priorTab);
            app.RelativedistributionlabelsEditFieldLabel.Text = 'Relative distribution labels';
            app.RelativedistributionlabelsEditField = uieditfield(app.priorTab, 'numeric');
            app.RelativedistributionlabelsEditField.ValueChangedFcn = createCallbackFcn(app, @RelativedistributionlabelsEditFieldValueChanged, true);
            app.RelativedistributionlabelsEditField.Value = 0.5;
            app.maxAUCEditFieldLabel = uilabel(app.priorTab);
            app.maxAUCEditFieldLabel.Text = 'max AUC';
            app.maxAUCEditField = uieditfield(app.priorTab, 'numeric');
            app.maxAUCEditField.Value = 0.7;
            app.minAUCLabel = uilabel(app.priorTab);
            app.minAUCLabel.Text = 'min AUC';
            app.minAUCEditField = uieditfield(app.priorTab, 'numeric');
            app.minAUCEditField.Value = 0.5;
            app.NbatchesEditFieldLabel = uilabel(app.priorTab);
            app.NbatchesEditFieldLabel.Text = 'N batches';
            app.NbatchesEditField = uieditfield(app.priorTab, 'numeric');
            app.BatchEditFieldLabel = uilabel(app.priorTab);
            app.BatchEditFieldLabel.Text = 'Batch %';
            app.BatchPercEditField = uieditfield(app.priorTab, 'numeric');
            app.BatchPercEditField.Value = 0.1;
            app.NApercaseEditFieldLabel = uilabel(app.priorTab);
            app.NApercaseEditFieldLabel.Text = '% NA per case';
            app.NApercaseEditField = uieditfield(app.priorTab, 'numeric');
            app.NAperfeatureEditFieldLabel = uilabel(app.priorTab);
            app.NAperfeatureEditFieldLabel.Text = '% NA per feature';
            app.NAperfeatureEditField = uieditfield(app.priorTab, 'numeric');
            app.MLmodelEditFieldLabel = uilabel(app.priorTab);
            app.MLmodelEditFieldLabel.Text = 'ML model';
            app.MLmodelEditField = uieditfield(app.priorTab, 'text');
            app.MLmodelEditField.Value = 'LIBKERNSVM';
            app.NcasesEditFieldLabel = uilabel(app.priorTab);
            app.NcasesEditFieldLabel.Text = 'N cases';
            app.NcasesEditField = uieditfield(app.priorTab, 'text');
            app.NcasesEditField.Value = '[100 200 300 400]';
            app.SimulateButton = uibutton(app.priorTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.postTab = uitab(app.TabGroup);
            app.postTab.Title = 'post';
            app.SimulateButton_post = uibutton(app.postTab, 'push');
            app.SimulateButton_post.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_postPushed, true);
            app.SimulateButton_post.Text = 'Simulate';
            app.NcasesEditField_2Label = uilabel(app.postTab);
            app.NcasesEditField_2Label.Text = 'N cases';
            app.NcasesEditField_post = uieditfield(app.postTab, 'text');
            app.NcasesEditField_post.Value = '[100 200 300 400]';
            app.AnalysesTextArea = uitextarea(app.postTab);
            app.AnalysesTextArea.Editable = 'off';
            app.AnalysesTextArea.Enable = 'off';
            app.AnalysistosimulateEditFieldLabel = uilabel(app.postTab);
            app.AnalysistosimulateEditFieldLabel.Text = 'Analysis to simulate ';
            app.AnalysistosimulateEditField = uieditfield(app.postTab, 'numeric');
            app.AnalysistosimulateEditField.Value = 1;
            app.OkButton = uibutton(app.postTab, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.post_2 = uitab(app.TabGroup);
            app.post_2.Title = 'post';
            app.SimulateButton_add_group = uibutton(app.post_2, 'push');
            app.SimulateButton_add_group.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_add_groupPushed, true);
            app.SimulateButton_add_group.Text = 'Simulate';
            app.AnalysesTextArea_2 = uitextarea(app.post_2);
            app.AnalysesTextArea_2.Editable = 'off';
            app.AnalysesTextArea_2.Enable = 'off';
            app.OkButton_2 = uibutton(app.post_2, 'push');
            app.OkButton_2.Text = 'Ok';
            app.NcasesEditField_2Label_3 = uilabel(app.post_2);
            app.NcasesEditField_2Label_3.Text = 'N cases ';
            app.NcasesEditField_add_data = uieditfield(app.post_2, 'text');
            app.NcasesEditField_add_data.Value = '[100 200 300 400; 0, 0, 0, 0]';
            app.AnalysistosimulateEditField_2Label = uilabel(app.post_2);
            app.AnalysistosimulateEditField_2Label.Text = 'Analysis to simulate ';
            app.AnalysistosimulateEditField_2 = uieditfield(app.post_2, 'numeric');
            app.AnalysistosimulateEditField_2.Value = 1;
            app.TextArea_5 = uitextarea(app.post_2);
            app.TextArea_5.Value = {'FORMAT'; 'Columns = n simulations to perform'; 'Rows = n groups / unique labels'; ''; 'e.g.'; '[100, 200, 300, 400; 0, 0, 0, 0] '; '-->  in 4 simulations, 100, 200, 300 and then 400 cases are simulated in the first group and 0 in the second.'};
            app.addCheckBox = uicheckbox(app.post_2);
            app.addCheckBox.Text = 'add to original data';
            app.post_oldTab = uitab(app.TabGroup);
            app.post_oldTab.Title = 'post_old';
            app.SelectButton_2 = uibutton(app.post_oldTab, 'push');
            app.SelectButton_2.Text = 'Select';
            app.OriginaldataEditField_2Label = uilabel(app.post_oldTab);
            app.OriginaldataEditField_2Label.Text = 'Original data';
            app.OriginaldataEditField_2 = uieditfield(app.post_oldTab, 'text');
            app.SimulateButton_post_2 = uibutton(app.post_oldTab, 'push');
            app.SimulateButton_post_2.Text = 'Simulate';
            app.NcasesEditField_2Label_2 = uilabel(app.post_oldTab);
            app.NcasesEditField_2Label_2.Text = 'N cases';
            app.NcasesEditField_post_2 = uieditfield(app.post_oldTab, 'text');
            app.NcasesEditField_post_2.Value = '[100 200 300 400]';
            app.LabelvariableEditField_2Label = uilabel(app.post_oldTab);
            app.LabelvariableEditField_2Label.Text = 'Label variable';
            app.LabelvariableEditField_2 = uieditfield(app.post_oldTab, 'text');
            app.LabelvariableEditField_2.Editable = 'off';
            app.TextArea_3 = uitextarea(app.post_oldTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Visible = 'off';
            app.ExcludeCheckBox_2 = uicheckbox(app.post_oldTab);
            app.ExcludeCheckBox_2.Enable = 'off';
            app.ExcludeCheckBox_2.Text = 'ID variable (to exclude)';
            app.ExcludeEditField_2 = uieditfield(app.post_oldTab, 'text');
            app.ExcludeEditField_2.Visible = 'off';
            app.TextArea_4 = uitextarea(app.post_oldTab);
            app.AnalysisNoEditField_2Label = uilabel(app.post_oldTab);
            app.AnalysisNoEditField_2Label.Text = 'Analysis No.';
            app.AnalysisNoEditField_2 = uieditfield(app.post_oldTab, 'numeric');
            app.AnalysisNoEditField_2.Value = 1;
            app.ExportfigureButton = uibutton(app.UIFigure, 'push');
            app.ExportfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportfigureButtonPushed, true);
            app.ExportfigureButton.Visible = 'off';
            app.ExportfigureButton.Text = 'Export figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simSample_App2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end